classdef BL_DipoleModeling_SimMEEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SourceModeling               matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadAnatomyMenu              matlab.ui.container.Menu
        LoadERPMenu                  matlab.ui.container.Menu
        LoadSolutionMenu             matlab.ui.container.Menu
        SaveSolutionMenu             matlab.ui.container.Menu
        AboutMenu                    matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RightPanel                   matlab.ui.container.Panel
        btn_export_SimMEEG           matlab.ui.control.Button
        panel_MRIViewer              matlab.ui.container.Panel
        slider_topo_scale            matlab.ui.control.RangeSlider
        TopographyScaleLabel         matlab.ui.control.Label
        edit_topo_scale_YLim2        matlab.ui.control.NumericEditField
        edit_topo_scale_YLim1        matlab.ui.control.NumericEditField
        btngrp_topo                  matlab.ui.container.ButtonGroup
        btn_topo_residual            matlab.ui.control.ToggleButton
        btn_topo_model               matlab.ui.control.ToggleButton
        btn_topo_data                matlab.ui.control.ToggleButton
        toggle_plot_true_pos         matlab.ui.control.StateButton
        btn_plot_headmodel           matlab.ui.control.StateButton
        DTransparencyLabel_3         matlab.ui.control.Label
        DTransparencyLabel_2         matlab.ui.control.Label
        slider_mri_transparency      matlab.ui.control.Slider
        MRITransparencyLabel         matlab.ui.control.Label
        slider_topo_transparency     matlab.ui.control.Slider
        slider_scalp_transparency    matlab.ui.control.Slider
        slider_brain_transparency    matlab.ui.control.Slider
        check_radiological_view      matlab.ui.control.CheckBox
        DTransparencyLabel           matlab.ui.control.Label
        btn_plot_mri                 matlab.ui.control.Button
        Axes_3D                      matlab.ui.control.UIAxes
        Axes_Coronal                 matlab.ui.control.UIAxes
        Axes_Axial                   matlab.ui.control.UIAxes
        Axes_Sagital                 matlab.ui.control.UIAxes
        panel_ERP                    matlab.ui.container.Panel
        btn_clear_erp_plot           matlab.ui.control.Button
        text_erp_file                matlab.ui.control.Label
        toggle_view_model            matlab.ui.control.StateButton
        toggle_view_residual         matlab.ui.control.StateButton
        toggle_view_data             matlab.ui.control.StateButton
        btn_reref                    matlab.ui.control.StateButton
        edit_trial                   matlab.ui.control.Spinner
        TrialSpinnerLabel            matlab.ui.control.Label
        edit_fit_interval            matlab.ui.control.EditField
        FitIntervalEditFieldLabel    matlab.ui.control.Label
        slider_trial                 matlab.ui.control.RangeSlider
        TrialsSliderLabel            matlab.ui.control.Label
        btn_plot_erp                 matlab.ui.control.Button
        listbox_sensors              matlab.ui.control.ListBox
        SensorListBoxLabel           matlab.ui.control.Label
        axes_trial_waves             matlab.ui.control.UIAxes
        axes_erp_waves               matlab.ui.control.UIAxes
        tab_modeling_grp             matlab.ui.container.TabGroup
        tab_dipfit                   matlab.ui.container.Tab
        panel_dipfit_params          matlab.ui.container.Panel
        edit_search_tolerance        matlab.ui.control.NumericEditField
        MaxIterLabel_3               matlab.ui.control.Label
        menu_search_grid             matlab.ui.control.DropDown
        GridDropDownLabel            matlab.ui.control.Label
        spinner_undo                 matlab.ui.control.Spinner
        undoSpinnerLabel             matlab.ui.control.Label
        btn_save_all_dip_soln        matlab.ui.control.Button
        btn_delete_dip_soln          matlab.ui.control.Button
        btn_copy_dip_soln            matlab.ui.control.Button
        menu_dip_soln                matlab.ui.control.DropDown
        btn_new_dip_soln             matlab.ui.control.Button
        edit_max_time                matlab.ui.control.NumericEditField
        MaxIterLabel_2               matlab.ui.control.Label
        check_parallel_compute       matlab.ui.control.CheckBox
        btn_save_dip_soln            matlab.ui.control.Button
        btn_load_dip_soln            matlab.ui.control.Button
        check_autoUpdate             matlab.ui.control.CheckBox
        btn_start_fit                matlab.ui.control.Button
        check_iterations             matlab.ui.control.CheckBox
        check_verbose                matlab.ui.control.CheckBox
        edit_max_iterations          matlab.ui.control.NumericEditField
        MaxIterLabel                 matlab.ui.control.Label
        menu_search_type             matlab.ui.control.DropDown
        SearchTypeLabel              matlab.ui.control.Label
        btngrp_fit_type              matlab.ui.container.ButtonGroup
        btn_fit_spatiotemp           matlab.ui.control.ToggleButton
        btn_fit_moving               matlab.ui.control.ToggleButton
        panel_dip_info               matlab.ui.container.Panel
        btn_invert_dip               matlab.ui.control.Button
        check_dip_sym_dist           matlab.ui.control.CheckBox
        edit_dip_sym_dist            matlab.ui.control.NumericEditField
        color_dip_clr                matlab.ui.control.ColorPicker
        btn_dip_add                  matlab.ui.control.Button
        btn_dip_delete               matlab.ui.control.Button
        btn_dip_delete_all           matlab.ui.control.Button
        edit_dip_pos                 matlab.ui.control.EditField
        edit_dip_ori                 matlab.ui.control.EditField
        switch_dip_on                matlab.ui.control.Switch
        switch_dip_fit               matlab.ui.control.Switch
        listbox_dips                 matlab.ui.control.ListBox
        DipoleListBoxLabel           matlab.ui.control.Label
        LocationLabel                matlab.ui.control.Label
        menu_dip_loc_fit             matlab.ui.control.DropDown
        LocLabel                     matlab.ui.control.Label
        menu_dip_symmetrical         matlab.ui.control.DropDown
        check_dip_search_radius      matlab.ui.control.CheckBox
        edit_dip_search_radius       matlab.ui.control.NumericEditField
        menu_dip_ori_fit             matlab.ui.control.DropDown
        OriLabel                     matlab.ui.control.Label
        OrientationazelLabel         matlab.ui.control.Label
        check_dip_vect               matlab.ui.control.CheckBox
        edit_dip_order               matlab.ui.control.NumericEditField
        FitOrderEditFieldLabel       matlab.ui.control.Label
        panel_dipfit_results         matlab.ui.container.Panel
        btn_plot_swf                 matlab.ui.control.Button
        check_plot_rms               matlab.ui.control.CheckBox
        edit_yoffset_swf             matlab.ui.control.NumericEditField
        OffsetEditFieldLabel         matlab.ui.control.Label
        slider_dip_waves_scale       matlab.ui.control.RangeSlider
        edit_axes_swf_YLim1          matlab.ui.control.NumericEditField
        edit_axes_swf_YLim2          matlab.ui.control.NumericEditField
        axes_dip_waves               matlab.ui.control.UIAxes
        axes_dip_resid_var           matlab.ui.control.UIAxes
        panel_main_menu              matlab.ui.container.Panel
        LeadFieldGainEditField       matlab.ui.control.NumericEditField
        LeadFieldGainEditFieldLabel  matlab.ui.control.Label
        text_RunTime                 matlab.ui.control.Label
        lamp_run                     matlab.ui.control.Lamp
        RunningLabel                 matlab.ui.control.Label
        menu_num_sensors             matlab.ui.control.DropDown
        NumberSensorsDropDownLabel   matlab.ui.control.Label
        btn_load_erp                 matlab.ui.control.Button
        menu_headmodel               matlab.ui.control.DropDown
        HeadModelDropDownLabel       matlab.ui.control.Label
        menu_sensor_type             matlab.ui.control.DropDown
        SensorTypeDropDownLabel      matlab.ui.control.Label
        btn_load_anatomy             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Axes_2D         % Axes - combining
        anatomy         % Anatomy structure from SimMEEG
        data            % data struct = .data [chans x samps x trials], .lat, .srate, .sens_label
        temp            % temporary variables that can be cleared
        erp             % ERP panel data;
        fit_params      % "dipfit_params" class created using "dipfit_params.m"
        dips            % "dipole" class created using "dipoles.m"
        model           % model struct of data
        h_plots          % all plot handles
        h               % global structure from SimMEEG
    end

    % Functions - Additional
    methods (Access = private)
    %% %%%%%%%%%%%%%%%%%%%%%%%%%%%%% ANATOMY Functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          %% Selects Headmodel based on SensorType & NumberSensors
          function SelectHeadModel(app)
              if contains(app.menu_sensor_type.Value,'EEG')
                  num_sens = app.menu_num_sensors.Value;
                  for v=1:size(app.anatomy.sens_eeg,2)
                      sens_num(v) = {num2str(length(app.anatomy.sens_eeg(v).label))};
                  end
                  sidx = strcmp(sens_num,num_sens);
                  app.anatomy.sens = app.anatomy.sens_eeg(sidx);        % using volume only right now
                  
                  switch app.menu_headmodel.Value
                      case 'Volume'
                          app.anatomy.headmodel = app.anatomy.headmodel_eeg_vol(sidx);
                          app.anatomy.leadfield = app.anatomy.leadfield_eeg_vol(sidx);
                      case 'Cortex'
                          app.anatomy.headmodel = app.anatomy.headmodel_eeg_cortex(sidx);
                          app.anatomy.leadfield = app.anatomy.leadfield_eeg_cortex(sidx);
                      case 'Spheres(Volume)'
                          app.anatomy.headmodel = app.anatomy.headmodel_eeg_spheres_vol(sidx);
                          app.anatomy.leadfield = app.anatomy.leadfield_eeg_spheres_vol(sidx);
                      case 'Spheres(Cortex)'
                          app.anatomy.headmodel = app.anatomy.headmodel_eeg_spheres_vol(sidx);
                          app.anatomy.leadfield = app.anatomy.leadfield_eeg_spheres_vol(sidx);
                  end

              elseif contains(app.menu_sensor_type.Value,'MEG')
                  num_sens = app.menu_num_sensors.Value;
                  for v=1:size(app.anatomy.sens_meg,2)
                      sens_num(v) = {num2str(length(app.anatomy.sens_meg(v).label))};
                  end
                  sidx = strcmp(sens_num,num_sens);
                  app.anatomy.sens = app.anatomy.sens_meg(sidx);        % using volume only right now

                  if contains(app.menu_headmodel.Value,'Volume')
                      app.anatomy.headmodel = app.anatomy.headmodel_meg_vol(sidx);        % using volume only right now
                      app.anatomy.leadfield = app.anatomy.leadfield_meg_vol(sidx); 
                  elseif contains(app.menu_headmodel.Value,'Cortex')
                      app.anatomy.headmodel = app.anatomy.headmodel_meg_cortex(sidx);        % using volume only right now
                      app.anatomy.leadfield = app.anatomy.leadfield_meg_cortex(sidx); 
                  elseif contains(app.menu_headmodel.Value,'Spheres')                   % using volume only right now
                      app.anatomy.headmodel = app.anatomy.headmodel_meg_spheres_vol(sidx);        
                      app.anatomy.leadfield = app.anatomy.leadfield_meg_spheres_vol(sidx); 
                  else
                      warndlg('Head Model not found in Anatomy','No Head Model')
                  end
              end
              %% Add "H" to leadfield
              x = cell2mat(app.anatomy.leadfield.leadfield); dims=size(x);
              app.anatomy.leadfield.H = reshape(x,[dims(1) 3 dims(2)/3]);
              app.anatomy.leadfield.voxel_pos = app.anatomy.leadfield.pos;

              
              %% calculating "Spheres" using ft_headmodel
              conduct = [0.3300, 0.0042, .3300, 0.3300]; % scalp, skull, CSF and brain
              bidx = 1:4; % 4-shell concentric spheres head model
              if contains(app.menu_headmodel.Value,'Spheres')

                  %% ft_hdm - need to convert to 'm' to find proper "inside"
                  units = 'm';
                  app.anatomy.ft_hdm_mesh = app.anatomy.mesh_volumes(bidx);
                  app.anatomy.ft_headmodel = ft_convert_units(app.anatomy.ft_hdm_mesh, units);
                  app.anatomy.ft_hdm = ft_headmodel_concentricspheres(app.anatomy.ft_headmodel, 'conductivity',conduct);
                  app.anatomy.ft_hdm = ft_convert_units(app.anatomy.ft_hdm, units);
                  app.anatomy.sens = ft_convert_units(app.anatomy.sens, units);
                  % [app.anatomy.ft_hdm, app.anatomy.sens] = ft_prepare_vol_sens(app.anatomy.ft_hdm, app.anatomy.sens);
                  [app.anatomy.ft_hdm] = ft_prepare_vol_sens(app.anatomy.ft_hdm, app.anatomy.sens);


                  %% set grid pos for leadfield search
                  grid_step = 1/1000;   % units 'mm'
                  hdm_outer = app.anatomy.ft_headmodel(1); % setting this to scalp for now
                  x=min(hdm_outer.pos(:,1)):grid_step:max(hdm_outer.pos(:,1));
                  y=min(hdm_outer.pos(:,2)):grid_step:max(hdm_outer.pos(:,2));
                  z=min(hdm_outer.pos(:,3)):grid_step:max(hdm_outer.pos(:,3));
                  [X,Y,Z] = meshgrid(x,y,z);
                  app.anatomy.lf_pos = cat(2, reshape(X,numel(X),1), reshape(Y,numel(Y),1), reshape(Z,numel(Z),1) );
                  %% find inside idx
                  [inside] = ft_inside_headmodel(app.anatomy.lf_pos, app.anatomy.ft_hdm);
                  app.anatomy.lf_pos = app.anatomy.lf_pos(inside,:);
                  fprintf('Lead Field Num Voxels: %.f\n',size(app.anatomy.lf_pos,1));
                  app.anatomy.hdm_tripos = hdm_outer;

                  %% convert units to 'mm'
                  units = 'mm';
                  app.anatomy.ft_headmodel = ft_convert_units(app.anatomy.ft_headmodel,units);
                  app.anatomy.ft_hdm = ft_convert_units(app.anatomy.ft_hdm,units);

              else
                  app.anatomy.hdm_tripos = app.anatomy.headmodel.bnd(1);
                  app.anatomy.ft_hdm = app.anatomy.headmodel;
                  app.anatomy.lf_pos = app.anatomy.leadfield.pos;
              end

              %% convert units to 'mm'
              units = 'mm';
              app.anatomy.mri = ft_convert_units(app.anatomy.mri,units);
              app.anatomy.leadfield = ft_convert_units(app.anatomy.leadfield,units);
              app.anatomy.headmodel = ft_convert_units(app.anatomy.headmodel,units);
              app.anatomy.mesh_volumes = ft_convert_units(app.anatomy.mesh_volumes,units);

              %% checking leadfield scale --> converting 
              if any(any(range(app.anatomy.leadfield.H,3)>1000))
                  app.anatomy.leadfield.H = app.anatomy.leadfield.H *1e-3;
              end

              
              app.anatomy.sens = ft_convert_units(app.anatomy.sens,units);
              app.anatomy.lf_pos = app.anatomy.lf_pos*1e3; % convert 'mm'
              app.anatomy.hdm_tripos.pos = app.anatomy.hdm_tripos.pos*1e3; % convert 'mm'

              %%
              fprintf('%s %.f\n', app.anatomy.sens.type, length(app.anatomy.sens.label))

          end
          %% Update MRI Axes
          function update_mri_axes(app)
              if app.check_radiological_view.Value==0 % flip MRI across hemispheres
                  y_dir = 'reverse';    % radiological view - hemisphere flipped view; left = RH and right = LH
              else
                  y_dir = 'normal';
              end
              % 2D
              app.Axes_Axial.Color = [1 1 1]*0;
              colormap(app.Axes_Axial,bone);  shading(app.Axes_Axial,'interp'); view(app.Axes_Axial,[-90 90]); app.Axes_Axial.YDir=y_dir; axis(app.Axes_Axial,'off');
              app.Axes_Sagital.Color = [1 1 1]*0; 
              colormap(app.Axes_Sagital,bone);  shading(app.Axes_Sagital,'interp'); view(app.Axes_Sagital,[-90 90]); app.Axes_Sagital.YDir=y_dir; axis(app.Axes_Sagital,'off');
              app.Axes_Coronal.Color = [1 1 1]*0; 
              colormap(app.Axes_Coronal,bone);  shading(app.Axes_Coronal,'interp'); view(app.Axes_Coronal,[-90 90]); axis(app.Axes_Coronal,'off');
              slider_transparencyValueChanged(app, []);
          end
          %% Find Nearest Voxel
          function find_nearest_voxel(app)
              %   finds the nearest voxel location based on least-squares fit between locations.
              app.temp.v_idx=[];
              for v=1:size(app.temp.xyz_locs,1)
                  [~,xy]=sort(sum((app.temp.xyz_voxels-repmat(app.temp.xyz_locs(v,:),[size(app.temp.xyz_voxels,1) 1])).^2,2),'ascend');
                  app.temp.v_idx(v)=xy(1);
              end
          end


    %% %%%%%%%%%%%%%%%%%%%%%%%%%%%%% PLOT Functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Plot MRI
        function plot_mri(app)
            %% Plot 2D slices
            app.Axes_Axial.clo; app.Axes_Sagital.clo; app.Axes_Coronal.clo;
            app.Axes_Axial.NextPlot = "add"; app.Axes_Sagital.NextPlot = "add"; app.Axes_Coronal.NextPlot = "add";

            ax_type = {'Axial' 'Sagital' 'Coronal'};
            midx = round(size(app.anatomy.mri.anatomy)/2);
            % if app.check_radiological_view.Value==0 % flip MRI across hemispheres
            %   mri = flipud(app.anatomy.mri.anatomy); hemi_labels = {'L' 'R'};
            % else
                mri = app.anatomy.mri.anatomy; hemi_labels = {'R' 'L'};
            % end

            img(1).img = squeeze(mri(:,:,midx(3))); % Axial
            img(2).img = squeeze(mri(:,midx(2),:)); % Sagital
            img(3).img = squeeze(mri(midx(1),:,:)); % Coronal
            for a=1:length(ax_type)
                app.temp.h_2Dimg(a).type = ax_type{a};
                app.temp.h_2Dimg(a).img = eval(sprintf('image(app.Axes_%s,img(%.f).img)',ax_type{a},a));
                app.temp.h_2Dimg(a).img.ButtonDownFcn = @app.add_dipoles;
            end
            % adding text to deignate LH RH
            text(app.Axes_Axial, app.Axes_Axial.XLim(2)-35, app.Axes_Axial.YLim(1)+20, 2, hemi_labels{2},'Color','w','FontSize',12); % left side of image
            text(app.Axes_Axial, app.Axes_Axial.XLim(2)-35, app.Axes_Axial.YLim(2)-20, 2, hemi_labels{1},'Color','w','FontSize',12); % right side of image
            text(app.Axes_Sagital, app.Axes_Sagital.XLim(2)-25, app.Axes_Sagital.YLim(1)+20, 2, hemi_labels{2},'Color','w','FontSize',12); % left side of image
            text(app.Axes_Sagital, app.Axes_Sagital.XLim(2)-25, app.Axes_Sagital.YLim(2)-20, 2, hemi_labels{1},'Color','w','FontSize',12); % right side of image
            
            
            %% Plot 3D MRI
            app.Axes_3D.clo;
            vol = app.anatomy.mesh_volumes([1 4]);
            vol(1).FaceColor = [1 .5 .5]; vol(1).EdgeColor = 'none'; vol(1).FaceAlpha = app.slider_scalp_transparency.Value/500; % scalp
            vol(2).FaceColor = [1 1 1]*.8; vol(2).EdgeColor = 'none'; vol(2).FaceAlpha = app.slider_scalp_transparency.Value/100; % brain
            opt.vol_nums = 1:2;
            app.temp.himg_3Dmesh = bl_plot_mesh(vol, opt, app.Axes_3D);
            
            % %% Plot headmodel 
            % app.Axes_3D.NextPlot = 'add';
            % pos = app.anatomy.leadfield.pos; 
            % app.temp.himg_3Dhdm = scatter3(app.Axes_3D, pos(:,1), pos(:,2), pos(:,3),'.','MarkerEdgeColor',[0 .6 0]); 

            %% Add Lighting
            b_val = .6; lgt_type = 'infinite';
            app.temp.camlight1 = light(app.Axes_3D, 'Color',[1 1 1]*b_val*.5, 'Style',lgt_type,'Position',[1 0 0]);    % front
            app.temp.camlight2 = light(app.Axes_3D, 'Color',[1 1 1]*b_val*.5, 'Style',lgt_type,'Position',[-1 0 0]);   % back
            app.temp.camlight3 = light(app.Axes_3D, 'Color',[1 1 1]*b_val, 'Style',lgt_type,'Position',[0 1 0]);    % left
            app.temp.camlight4 = light(app.Axes_3D, 'Color',[1 1 1]*b_val, 'Style',lgt_type,'Position',[0 -1 0]);   % right
            app.temp.camlight5 = light(app.Axes_3D, 'Color',[1 1 1]*b_val*.5, 'Style',lgt_type,'Position',[0 0 1]);    % top
            app.temp.camlight6 = light(app.Axes_3D, 'Color',[1 1 1]*b_val*.5, 'Style',lgt_type,'Position',[0 0 -1]);   % bottom
            lighting(app.Axes_3D,'gouraud');
            % app.Axes_3D.View = [-90 90];

            
            %% Plot Dipoles
            plot_dips(app);
            %% Plot True locs
            toggle_plot_true_posValueChanged(app, []);
            %% Plot Topo
            plot_topo(app);
            if isfield(app.h_plots,'topo_colorbar')
                delete(app.h_plots.topo_colorbar)
                app.temp.colorbar_pos = [385 109 150 8]; 
                app.h_plots.topo_colorbar = colorbar(app.Axes_3D,'Location','southoutside','Units','pixels','Position',app.temp.colorbar_pos,'Ticks',[]);
            else
                app.temp.colorbar_pos = [385 109 150 8]; 
                app.h_plots.topo_colorbar = colorbar(app.Axes_3D,'Location','southoutside','Units','pixels','Position',app.temp.colorbar_pos,'Ticks',[]);
            end
           event.Source.Tag='slider'; slider_topo_scaleValueChanged(app, event);
            
            %% Update Axes
            update_mri_axes(app);
            update_mri_slices(app);

        end
        %% Plot Dipoles
        function plot_dips(app)
            %% PLot Dipoles
            app.Axes_3D.NextPlot = "add";
            if ~isempty(app.dips)
                if any(isnan(app.dips(1).mri_pos)) % only 1 dipole at 1st pixel == program startup so don't plot
                else
                    scale_fact = 1.5; %100/range(app.axes_dip_waves.YLim);
                    for didx=1:size(app.dips,2)
                        %% plot on 2D MRI slices
                        app.dips(didx).mrk_handles(1) = scatter(app.Axes_Axial,   app.dips(didx).mri_pos([2]), app.dips(didx).mri_pos([1]),'filled','o','MarkerEdgeColor','k','MarkerFaceColor', app.dips(didx).dip_clr, 'ButtonDownFcn', {@app.set_drag_obj}, 'Tag', sprintf('%.f',didx));
                        app.dips(didx).mrk_handles(2) = scatter(app.Axes_Sagital, app.dips(didx).mri_pos([3]), app.dips(didx).mri_pos([1]),'filled','o','MarkerEdgeColor','k','MarkerFaceColor', app.dips(didx).dip_clr, 'ButtonDownFcn', {@app.set_drag_obj}, 'Tag', sprintf('%.f',didx));
                        app.dips(didx).mrk_handles(3) = scatter(app.Axes_Coronal, app.dips(didx).mri_pos([3]), app.dips(didx).mri_pos([2]),'filled','o','MarkerEdgeColor','k','MarkerFaceColor', app.dips(didx).dip_clr, 'ButtonDownFcn', {@app.set_drag_obj}, 'Tag', sprintf('%.f',didx));

                        %% plot on 3D anatomy
                        app.dips(didx).mrk_handles(4) = scatter3(app.Axes_3D, app.dips(didx).dip_pos(1), app.dips(didx).dip_pos(2), app.dips(didx).dip_pos(3),'filled','o','MarkerEdgeColor','k','MarkerFaceColor', app.dips(didx).dip_clr, 'ButtonDownFcn', {@app.set_drag_obj}, 'Tag', sprintf('%.f',didx));
                        % Ori
                        dip_ori = deg2rad(app.dips(didx).dip_ori);    % az, el
                        [x,y,z] = sph2cart(dip_ori(:,1), dip_ori(:,2), ones(size(dip_ori,1),1));
                        dip_ori = [x y z];
                        if ~isempty(app.dips(didx).swf)
                            ori_length = rms(app.dips(didx).swf(:,app.model.fit_samps)*app.temp.convert_factor,2)*scale_fact;
                        else
                            ori_length = [1 1 1]*20;
                        end
                        if app.dips(didx).vect_idx==0  % scalar dipole so plot 1-dipole plane
                            app.dips(didx).ori_handles = plot3(app.Axes_3D, app.dips(didx).dip_pos(1)+[0 dip_ori(1)*ori_length(1)], app.dips(didx).dip_pos(2)+[0 dip_ori(2)*ori_length(1)], app.dips(didx).dip_pos(3)+[0 dip_ori(3)*ori_length(1)],'Color',app.dips(didx).dip_clr, 'LineWidth',2);
                        else % vector dipole so plot 3-dipole vectors
                            app.dips(didx).ori_handles(1) = plot3(app.Axes_3D, app.dips(didx).dip_pos(1)+[0 1]'*ori_length(1), app.dips(didx).dip_pos(2)+[0 0]'*ori_length(1), app.dips(didx).dip_pos(3)+[0 0]'*ori_length(1),'Color',app.dips(didx).dip_clr, 'LineWidth',2);
                            if length(ori_length)>1
                                app.dips(didx).ori_handles(2) = plot3(app.Axes_3D, app.dips(didx).dip_pos(1)+[0 0]'*ori_length(2), app.dips(didx).dip_pos(2)+[0 1]'*ori_length(2), app.dips(didx).dip_pos(3)+[0 0]'*ori_length(2),'Color',app.dips(didx).dip_clr, 'LineWidth',2);
                                app.dips(didx).ori_handles(3) = plot3(app.Axes_3D, app.dips(didx).dip_pos(1)+[0 0]'*ori_length(3), app.dips(didx).dip_pos(2)+[0 0]'*ori_length(3), app.dips(didx).dip_pos(3)+[0 1]'*ori_length(3),'Color',app.dips(didx).dip_clr, 'LineWidth',2);
                            end
                        end

                        if app.dips(didx).dip_on==0
                            set(app.dips(didx).mrk_handles,'Visible','off'); set(app.dips(didx).wav_handles,'Visible','off');
                        end
                    end
                end
            end
        end
        %% Plot True Locs
        function plot_true_pos(app)
            if isfield(app.h,'cfg')
                true_pos =  round( ft_warp_apply(inv(app.anatomy.mri.transform), app.h.cfg.source.vx_locs) ); 
                %% plot true locs
                app.h_plots.h_2D_true_pos(1) = scatter(app.Axes_Axial,   true_pos(:,2), true_pos(:,1),'filled','s','MarkerEdgeColor','m','MarkerFaceColor', 'none','LineWidth',2);
                app.h_plots.h_2D_true_pos(2) = scatter(app.Axes_Sagital, true_pos(:,3), true_pos(:,1),'filled','s','MarkerEdgeColor','m','MarkerFaceColor', 'none','LineWidth',2);
                app.h_plots.h_2D_true_pos(3) = scatter(app.Axes_Coronal, true_pos(:,3), true_pos(:,2),'filled','s','MarkerEdgeColor','m','MarkerFaceColor', 'none','LineWidth',2);
            end            
        end
        %% Plot ERP
        function plot_erp(app,~)

            if isempty(app.h_plots.h_overlay_data)  || isempty(app.h_plots.h_trial_data)
                vidx = 1:size(app.data.data,2);
                app.axes_erp_waves.clo;
                app.axes_erp_waves.NextPlot ="replace";
                app.axes_trial_waves.NextPlot ="replace";
                if isfield(app.data,'data')
                    if ~isempty(app.data.data)
                        %% get trial index
                        if app.slider_trial.Value(1)==0
                            tidx = [1 1];
                        else
                            tidx = app.slider_trial.Value;
                        end

                        %% Plot ERP/ERF Overlay
                        plot(app.axes_erp_waves, [0 0], app.axes_erp_waves.YLim,'--','Color',[1 1 1]*.6);
                        app.axes_erp_waves.NextPlot ="add";
                        plot(app.axes_erp_waves, app.data.lat([1 end]), [0 0],'--','Color',[1 1 1]*.6);
                        % app.h_plots.h_overlay_data = plot(app.axes_erp_waves, app.data.lat, mean(app.data.data(:,vidx,:),3),'k');
                        app.h_plots.h_overlay_data = plot(app.axes_erp_waves, app.data.lat, app.data.avg_data(:,vidx,:),'k');
                        max_val = max(max(abs(mean(app.data.data,3))));
                        app.axes_erp_waves.YLim = [-max_val max_val]*1.2;

                        %% Plot Trials
                        v_offset = -20;
                        app.axes_trial_waves.YLim = [length(vidx)*v_offset -v_offset];
                        plot(app.axes_trial_waves, [0 0], app.axes_trial_waves.YLim,'--','Color',[1 1 1]*.6);
                        app.axes_trial_waves.NextPlot ="add";
 
                        clear app.h_plots.h_trial_data;
                        for v=1:length(vidx) % plotting all then turn them visible on/off when needing to plot
                            % app.h_plots.h_trial_data(v) = plot(app.axes_trial_waves, app.data.lat, mean(app.data.data(:,vidx(v),tidx),3) + ((v-1)*v_offset),'k');
                            xdata = app.data.data(:,vidx(v),tidx(1):tidx(2));
                            xdata = reshape(xdata, numel(xdata),1);
                            if size(xdata,1)~=size(app.data.lat,2)
                                clat = 0:1/app.data.srate:size(xdata,1)/app.data.srate; % latency for concatenated data
                                clat = clat(1:size(xdata,1));
                            else
                                clat = app.data.lat;
                            end
                            app.h_plots.h_trial_data(v) = plot(app.axes_trial_waves, clat, xdata + ((v-1)*v_offset),'k');
                            
                            app.h_plots.h_trial_data_txt(v) = text(app.axes_trial_waves, clat(2), ((v-1.2)*v_offset), app.data.sens_label(vidx(v)),'Color','b');
                            app.axes_trial_waves.NextPlot ="add";
                        end
                        app.axes_trial_waves.YLim = [length(vidx)*v_offset -v_offset];
                    end
                end
                plot_time_int(app)
            end
            app.axes_erp_waves.ButtonDownFcn = @app.click_drag_interval;
            toggle_erp_data(app);
        end
        %% Plot topo
        function plot_topo(app)
            val = [];
            app.btn_topo_data.BackgroundColor = 'w'; app.btn_topo_data.FontColor = 'k';
            app.btn_topo_model.BackgroundColor = [.96 1 .96]; app.btn_topo_model.FontColor = [0 .6 0];
            app.btn_topo_residual.BackgroundColor = [1 .96 .96]; app.btn_topo_residual.FontColor = 'r';

            if ~isempty(app.data.avg_data) && app.btn_topo_data.Value == 1
                val = mean(app.data.avg_data(app.model.fit_samps,:),1);
                app.btn_topo_data.BackgroundColor = 'k'; app.btn_topo_data.FontColor = 'w';
            elseif ~isempty(app.data.model_data) && app.btn_topo_model.Value == 1
                val = mean(mean(app.data.model_data(app.model.fit_samps,:,:),3),1);
                app.btn_topo_model.BackgroundColor = [0 .6 0]; app.btn_topo_model.FontColor = [.96 1 .96];
            elseif ~isempty(app.data.residual_data) && app.btn_topo_residual.Value == 1
                val = mean(mean(app.data.residual_data(app.model.fit_samps,:,:),3),1);
                app.btn_topo_residual.BackgroundColor = 'r'; app.btn_topo_residual.FontColor = [1 .96 .96];
            else
                val = [];
            end

            if ~isempty(val)
                if isfield(app.temp,'h_topo') && ~isempty(app.temp.h_topo)
                    if isvalid(app.temp.h_topo); delete(app.temp.h_topo); end
                end
                app.Axes_3D.SortMethod='depth'; app.Axes_3D.Colormap = jet;
                pos = app.anatomy.sens.chanpos*1.11;
                [app.temp.h_topo] = bl_ft_plot_topo3d(app.Axes_3D, pos, val,'facealpha',app.slider_topo_transparency.Value/100);
                app.Axes_3D.CLim = app.axes_erp_waves.YLim;
            else
                 if isfield(app.temp,'h_topo') && ~isempty(app.temp.h_topo)
                    if isvalid(app.temp.h_topo); delete(app.temp.h_topo); end
                end
            end
            event.Source.Tag='slider'; slider_topo_scaleValueChanged(app, event);

        end

        %% plot model & residual data
        function plot_model_waves(app)
            %% replot all
            btn_clear_erp_plotPushed(app,[]); plot_erp(app);
            app.h_plots.h_overlay_model = plot(app.axes_erp_waves, app.data.lat, mean(app.data.model_data,3),'Color',[0 .6 0]);
            app.h_plots.h_overlay_residual = plot(app.axes_erp_waves, app.data.lat, mean(app.data.residual_data,3),'r');
            toggle_view_ValueChanged(app, []);
        end
        %% Toggle ERP data
        function toggle_erp_data(app)
            %% Data
            if app.toggle_view_data.Value==1 && ~isempty(app.h_plots.h_overlay_data) && all(isvalid(app.h_plots.h_overlay_data))
                app.toggle_view_data.BackgroundColor = [0 0 0]; app.toggle_view_data.FontColor = [1 1 1];
                set(app.h_plots.h_overlay_data,'Visible',"off"); set(app.h_plots.h_overlay_data(app.listbox_sensors.ValueIndex),'Visible',"on");
                set(app.h_plots.h_trial_data,'Visible',"off"); set(app.h_plots.h_trial_data(app.listbox_sensors.ValueIndex),'Visible',"on"); 
                set(app.h_plots.h_trial_data_txt,'Visible',"off"); set(app.h_plots.h_trial_data_txt(app.listbox_sensors.ValueIndex),'Visible',"on");
                
                %% Set trial Yscale
                min_max = [min([app.h_plots.h_trial_data(app.listbox_sensors.ValueIndex).YData]) max([app.h_plots.h_trial_data(app.listbox_sensors.ValueIndex).YData])];
                app.axes_trial_waves.YLim = min_max;

            else
                app.toggle_view_data.BackgroundColor = [1 1 1]; app.toggle_view_data.FontColor = [0 0 0];
                set(app.h_plots.h_overlay_data,'Visible',"off"); set(app.h_plots.h_trial_data,'Visible',"off"); set(app.h_plots.h_trial_data_txt,'Visible',"off"); 
            end
            %% Model
            if app.toggle_view_model.Value==1 && ~isempty(app.h_plots.h_overlay_model) && all(isvalid(app.h_plots.h_overlay_model))
                app.toggle_view_model.BackgroundColor = [0 .6 0]; app.toggle_view_model.FontColor = [1 1 1];
                set(app.h_plots.h_overlay_model,'Visible',"off"); set(app.h_plots.h_overlay_model(app.listbox_sensors.ValueIndex),'Visible',"on");
            else
                app.toggle_view_model.BackgroundColor = [.95 1 .95]; app.toggle_view_model.FontColor = [0 .6 0];
                if ~isempty(app.h_plots.h_overlay_model) && all(isvalid(app.h_plots.h_overlay_model)); set(app.h_plots.h_overlay_model,'Visible',"off"); end
            end
            %% Residual
            if app.toggle_view_residual.Value==1 && ~isempty(app.h_plots.h_overlay_residual) && all(isvalid(app.h_plots.h_overlay_residual))
                app.toggle_view_residual.BackgroundColor = [1 0 0]; app.toggle_view_residual.FontColor = [1 1 1];
                set(app.h_plots.h_overlay_residual,'Visible',"off"); set(app.h_plots.h_overlay_residual(app.listbox_sensors.ValueIndex),'Visible',"on");
            else
                app.toggle_view_residual.BackgroundColor = [1 .95 .95]; app.toggle_view_residual.FontColor = [1 0 0];
                if ~isempty(app.h_plots.h_overlay_residual) && all(isvalid(app.h_plots.h_overlay_residual)); set(app.h_plots.h_overlay_residual,'Visible',"off"); end
            end
        end
    %% %%%%%%%%%%%%%%%%%%%%%%%%%%%%% DIPOLE MODELING Functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Get Active Interval - Click & Drag
        function click_drag_interval(app, source, event)
            app.temp.tic = tic;  rotate3d(app.SourceModeling,"off"); 
            app.SourceModeling.WindowButtonMotionFcn = @app.get_mouse_pos;
            app.SourceModeling.WindowButtonUpFcn = @app.stop_mouse_pos;
            app.model.fit_int(1) = event.Source.CurrentPoint(1,1);
            app.edit_fit_interval.Value = sprintf('%.3f %.3f', app.model.fit_int);
        end
        %% Stop mouse
        function stop_mouse_pos(app,source,event)
            app.SourceModeling.WindowButtonUpFcn = '';
            app.SourceModeling.WindowButtonMotionFcn = '';
            app.SourceModeling.WindowButtonUpFcn = '';
            app.model.fit_int(2) = source.CurrentAxes.CurrentPoint(1,1);
            app.model.fit_int = sort(app.model.fit_int);
            ss=find(app.data.lat<=app.model.fit_int(1)); xs(1)=ss(end); ss=find(app.data.lat<=app.model.fit_int(2)); xs(2)=ss(end);
            app.model.fit_samps = xs(1):xs(2);

            app.edit_fit_interval.Value = sprintf('%.3f %.3f', app.model.fit_int);
            
            %% get resid var for interval
            if ~isempty(app.model.resid_var)
                app.axes_dip_resid_var.Title.String = sprintf('Residual Variance: Avg = %.1f%% (Best = %.1f%%)', mean(app.model.resid_var(app.model.fit_samps))*100, min(app.model.resid_var(app.model.fit_samps))*100 );
            end
            

            %%  
            plot_time_int(app);
            plot_topo(app);

        end
        %% get mouse position
        function get_mouse_pos(app,source,event)
            app.model.fit_int(2) = event.Source.CurrentAxes.CurrentPoint(1);
            app.edit_fit_interval.Value = sprintf('%.3f %.3f', sort(app.model.fit_int));
            if toc(app.temp.tic)>5
                app.stop_mouse_pos(source);
            end
        end
        %% Plot Time int
        function plot_time_int(app)
            %% set fit samps
            ss=find(app.data.lat<=app.model.fit_int(1)); xs(1)=ss(end); ss=find(app.data.lat<=app.model.fit_int(2)); xs(2)=ss(end);
            app.model.fit_samps = xs(1):xs(2);

            ov_fcn = app.axes_erp_waves.ButtonDownFcn;
            if ~isempty(app.model.fit_int_plot_overlay)
                if all(isvalid(app.model.fit_int_plot_overlay))     % update time int
                    delete(app.model.fit_int_area_overlay); delete(app.model.fit_int_plot_overlay);
                    delete(app.model.fit_int_area_trial); delete(app.model.fit_int_plot_trial);
                    delete(app.model.fit_int_area_resid_var); delete(app.model.fit_int_plot_resid_var);
                    delete(app.model.fit_int_area_swf); delete(app.model.fit_int_plot_swf);
                end
            end

                %% Plot time interval
                % Overlay
                hax = app.axes_erp_waves;
                hax.NextPlot ="add";
                app.model.fit_int_plot_overlay = plot(hax,  [app.model.fit_int(1)  app.model.fit_int(1); app.model.fit_int(2)  app.model.fit_int(2)]', [hax.YLim; hax.YLim]', 'r--');
                app.model.fit_int_area_overlay = area(hax, [app.model.fit_int(1)  app.model.fit_int(2)], [hax.YLim(2) hax.YLim(2)], hax.YLim(1),'FaceAlpha',.1,'FaceColor','y','EdgeColor','none');
                % Trials
                hax = app.axes_trial_waves;
                hax.NextPlot ="add";
                app.model.fit_int_plot_trial = plot(hax,  [app.model.fit_int(1)  app.model.fit_int(1); app.model.fit_int(2)  app.model.fit_int(2)]', [hax.YLim; hax.YLim]', 'r--');
                app.model.fit_int_area_trial = area(hax, [app.model.fit_int(1)  app.model.fit_int(2)], [hax.YLim(2) hax.YLim(2)], hax.YLim(1),'FaceAlpha',.1,'FaceColor','y','EdgeColor','none');
                % Residual Variance
                hax = app.axes_dip_resid_var; hax.XLim = app.data.lat([1 end]);
                hax.NextPlot ="add";
                if isfield(app.model,'fit_int_plot_resid_var')
                if ishandle(app.model.fit_int_plot_resid_var); delete(app.model.fit_int_area_resid_var); delete(app.model.fit_int_plot_resid_var); end
                end
                app.model.fit_int_plot_resid_var = plot(hax,  [app.model.fit_int(1)  app.model.fit_int(1); app.model.fit_int(2)  app.model.fit_int(2)]', [hax.YLim; hax.YLim]', 'r--');
                app.model.fit_int_area_resid_var = area(hax, [app.model.fit_int(1)  app.model.fit_int(2)], [hax.YLim(2) hax.YLim(2)], hax.YLim(1),'FaceAlpha',.1,'FaceColor','y','EdgeColor','none');
                % Source Waves
                hax = app.axes_dip_waves; hax.XLim = app.data.lat([1 end]);
                hax.NextPlot ="add";
                if isfield(app.model,'fit_int_plot_swf')
                if ishandle(app.model.fit_int_plot_swf); delete(app.model.fit_int_area_swf); delete(app.model.fit_int_plot_swf); end
                end
                app.model.fit_int_plot_swf = plot(hax,  [app.model.fit_int(1)  app.model.fit_int(1); app.model.fit_int(2)  app.model.fit_int(2)]', [hax.YLim; hax.YLim]', 'r--');
                app.model.fit_int_area_swf = area(hax, [app.model.fit_int(1)  app.model.fit_int(2)], [hax.YLim(2) hax.YLim(2)], hax.YLim(1),'FaceAlpha',.1,'FaceColor','y','EdgeColor','none');
                app.axes_dip_waves.Children = flipud(app.axes_dip_waves.Children); 

                app.axes_erp_waves.ButtonDownFcn = ov_fcn;
        end

        %% Set Dip Obj properties from settings in "panel_dip_info"
        function update_dips(app)
            if ~isempty(app.temp.dip_idx)
                app.dips(app.temp.dip_idx).get_dip_pos(app.anatomy.mri.transform);
                app.dips(app.temp.dip_idx).dip_ori = str2num(app.edit_dip_ori.Value);
                app.dips(app.temp.dip_idx).dip_on = strcmpi(app.switch_dip_on.Value,"On");
                % if strcmpi(app.menu_dip_loc_fit.Value,'fixed')
                %     app.switch_dip_fit.Value = "No Fit"; app.switch_dip_fit.FontColor = 'r'; 
                % else
                %     app.switch_dip_fit.Value = "Fit"; app.switch_dip_fit.FontColor = [0 .6 0]; 
                % end
                app.dips(app.temp.dip_idx).fit_on = strcmpi(app.switch_dip_fit.Value,"Fit"); % && ~strcmpi(app.menu_dip_loc_fit.Value,'fixed'); %&& strcmpi(app.menu_dip_ori_fit.Value,'fixed');
                app.dips(app.temp.dip_idx).fit_order = app.edit_dip_order.Value;
                %% Sym
                app.dips(app.temp.dip_idx).sym_type = app.menu_dip_loc_fit.Value;
                if strcmpi(app.menu_dip_loc_fit.Value,"symmetric"); app.dips(app.temp.dip_idx).sym_idx = str2num(app.menu_dip_symmetrical.Value); else; app.dips(app.temp.dip_idx).sym_idx = 0; end
                %
                if app.check_dip_sym_dist.Value==1
                    app.dips(app.temp.dip_idx).sym_dist = abs(app.edit_dip_sym_dist.Value);
                else
                    app.dips(app.temp.dip_idx).sym_dist = abs(app.edit_dip_sym_dist.Value)*-1; % no search radius
                end

                %%ori
                app.dips(app.temp.dip_idx).ori_type = app.menu_dip_ori_fit.Value;
                if strcmpi(app.menu_dip_ori_fit.Value,"free"); app.dips(app.temp.dip_idx).fit_ori = 1; else; app.dips(app.temp.dip_idx).fit_ori = 0; end
                app.dips(app.temp.dip_idx).vect_idx = app.check_dip_vect.Value;
                %% search radius
                if app.check_dip_search_radius.Value==1
                    app.dips(app.temp.dip_idx).search_radius = abs(app.edit_dip_search_radius.Value);
                else
                    app.dips(app.temp.dip_idx).search_radius = abs(app.edit_dip_search_radius.Value)*-1; % no search radius
                end
                app.dips(app.temp.dip_idx).search_pos = app.dips(app.temp.dip_idx).dip_pos;
                

                %% dip_clr
                app.dips(app.temp.dip_idx).dip_clr = app.color_dip_clr.Value; 

                %% Update loc and clr on MRIs
                update_mri_pos(app); % update dipole's mri_pos on MRIs
                set(app.dips(app.temp.dip_idx).mrk_handles,'MarkerFaceColor',app.dips(app.temp.dip_idx).dip_clr);
                set(app.dips(app.temp.dip_idx).ori_handles,'Color',app.dips(app.temp.dip_idx).dip_clr);
                
            end
        end
        %% Update Fit Params
        function update_fit_params(app)
            app.fit_params.spatiotemp = app.btn_fit_spatiotemp.Value;
            app.fit_params.optimType  = app.menu_search_type.Value;
            app.fit_params.fcn_tolerance = app.edit_search_tolerance.Value; % same tolerances for now because limited space in GUI
            app.fit_params.step_tolerance = app.edit_search_tolerance.Value;
            app.fit_params.maxIter = app.edit_max_iterations.Value;
            app.fit_params.maxTime = app.edit_max_time.Value;
            app.fit_params.parallel_compute = logical(app.check_parallel_compute.Value);
            if app.check_verbose.Value ==1; app.fit_params.verbose = 'iter'; else; app.fit_params.verbose = 'off'; end
        end

        %% Update Dip Info Panel
        function update_dip_info_panel(app)
            % disp(app.dips(app.temp.dip_idx))
            if isempty(app.dips(app.temp.dip_idx))
                fprintf('No Dipoles Added\n');
            else
                %% Listbox
                if size(app.dips,2)==0
                    app.temp.dip_idx = [];
                    app.edit_dip_pos.Value = 'nan nan nan';
                    app.edit_dip_ori.Value = 'nan nan';
                else
                    for v=1:size(app.dips,2); dnames(v) = {sprintf('%.f',v)}; end
                    app.listbox_dips.Items = dnames;
                    app.listbox_dips.ValueIndex = app.temp.dip_idx;

                    %% add color to listbox
                    for v=1:size(app.dips,2)
                        s = uistyle(FontColor =  app.dips(v).dip_clr);
                        addStyle(app.listbox_dips,s,"item",v);
                    end

                    %% dip_pos
                    app.edit_dip_pos.Value = sprintf('%.1f %.1f %.1f', app.dips(app.temp.dip_idx).dip_pos); app.edit_dip_pos.FontColor = app.dips(app.temp.dip_idx).dip_clr;
                    app.edit_dip_ori.Value = sprintf('%.f %.f', app.dips(app.temp.dip_idx).dip_ori); app.edit_dip_ori.FontColor = app.dips(app.temp.dip_idx).dip_clr;
                    %% sym_type
                    app.menu_dip_loc_fit.Value = app.dips(app.temp.dip_idx).sym_type;
                    if strcmpi(app.menu_dip_loc_fit.Value,"symmetric")
                        num_dips = size(app.dips,2);
                        if num_dips>1
                            sym_dips = setxor(1:num_dips, app.temp.dip_idx);
                            app.menu_dip_symmetrical.Items = dnames(sym_dips);

                            if ismember(num2str(app.dips(app.temp.dip_idx).sym_idx), app.menu_dip_symmetrical.Items)
                                app.menu_dip_symmetrical.Value = num2str(app.dips(app.temp.dip_idx).sym_idx);
                            else
                                app.menu_dip_symmetrical.Value = app.menu_dip_symmetrical.Items(1);
                            end

                        else
                            app.menu_dip_symmetrical.Items = {'0'}; app.menu_dip_symmetrical.Value = {'0'}; app.menu_dip_symmetrical.ValueIndex = 1;
                        end
                    else
                        app.menu_dip_symmetrical.Items = {'0'}; app.menu_dip_symmetrical.Value = {'0'}; app.menu_dip_symmetrical.ValueIndex = 1;
                    end


                    %% ori_type
                    app.menu_dip_ori_fit.Value = app.dips(app.temp.dip_idx).ori_type;
                    app.check_dip_vect.Value = app.dips(app.temp.dip_idx).vect_idx;
                    %% search_radius
                    if app.dips(app.temp.dip_idx).search_radius<0
                    app.check_dip_search_radius.Value = 0; app.edit_dip_search_radius.Enable = "off";
                    else
                        app.check_dip_search_radius.Value = 1; app.edit_dip_search_radius.Enable = "on";
                    end
                    app.edit_dip_search_radius.Value = app.dips(app.temp.dip_idx).search_radius;
                    
                    %% dip_clr
                    app.color_dip_clr.Value = app.dips(app.temp.dip_idx).dip_clr;
                    %% dip_on
                    if logical(app.dips(app.temp.dip_idx).dip_on)
                        app.switch_dip_on.Value = "On"; app.switch_dip_on.FontColor = [0 .6 0]; app.switch_dip_fit.Enable = "on"; app.edit_dip_order.Enable = "on";
                    else
                        app.switch_dip_on.Value = "Off"; app.switch_dip_on.FontColor = [1 0 0]; app.switch_dip_fit.Enable = "off"; app.edit_dip_order.Enable = "off";
                    end
                    %% fit_on
                    if logical(app.dips(app.temp.dip_idx).fit_on)
                        app.switch_dip_fit.Value = "Fit"; app.switch_dip_fit.FontColor = [0 .6 0];
                    else
                        app.switch_dip_fit.Value = "No Fit"; app.switch_dip_fit.FontColor = [1 0 0];
                    end
                    app.edit_dip_order.Value = app.dips(app.temp.dip_idx).fit_order;

                end
            end
        end
        %% Update mri_pos
        function update_mri_pos(app)
            if any(isnan(app.dips(app.temp.dip_idx).mri_pos))
            else
                cx = [2 1; 3 1; 3 2]; % Note cpt position is rotated 90 compared to image so X=2, Y=1, Z=3
                %% update dip on 2D MRIs
                for a=1:3
                    set(app.dips(app.temp.dip_idx).mrk_handles(a),'XData', app.dips(app.temp.dip_idx).mri_pos(cx(a,1)));
                    set(app.dips(app.temp.dip_idx).mrk_handles(a),'YData', app.dips(app.temp.dip_idx).mri_pos(cx(a,2)));
                    if app.dips(app.temp.dip_idx).dip_on==0
                        set(app.dips(app.temp.dip_idx).mrk_handles(a), 'Visible','off'); 
                        try set(app.dips(app.temp.dip_idx).wav_handles(a), 'Visible','off'); catch; end
                    else
                        set(app.dips(app.temp.dip_idx).mrk_handles(a), 'Visible','on'); 
                        try set(app.dips(app.temp.dip_idx).wav_handles(a), 'Visible','on'); catch; end
                    end
                end
                %% Update dip location on 3D image
                set(app.dips(app.temp.dip_idx).mrk_handles(4),'XData', app.dips(app.temp.dip_idx).dip_pos(1));
                set(app.dips(app.temp.dip_idx).mrk_handles(4),'YData', app.dips(app.temp.dip_idx).dip_pos(2));
                set(app.dips(app.temp.dip_idx).mrk_handles(4),'ZData', app.dips(app.temp.dip_idx).dip_pos(3));
                %% Update dip Ori on 3D image
                didx = app.temp.dip_idx;
                scale_fact = 1.5; %100/app.edit_yoffset_swf.Value;
                if app.dips(didx).vect_idx==0 % scalar dipole

                    if ~isempty(app.dips(didx).swf)
                        ori_length = rms(app.dips(didx).swf(app.model.fit_samps)*app.temp.convert_factor)*scale_fact;
                    else
                        ori_length = [1]*20;
                    end
                    dip_ori = deg2rad(app.dips(didx).dip_ori);    % az, el
                    [x,y,z] = sph2cart(dip_ori(:,1), dip_ori(:,2), ones(size(dip_ori,1),1));
                    dip_ori = [x y z];

                    x = app.dips(didx).dip_pos(1)+[0 dip_ori(1)]*ori_length(1);
                    y = app.dips(didx).dip_pos(2)+[0 dip_ori(2)]*ori_length(1);
                    z = app.dips(didx).dip_pos(3)+[0 dip_ori(3)]*ori_length(1);
                    set(app.dips(app.temp.dip_idx).ori_handles,'XData', x);
                    set(app.dips(app.temp.dip_idx).ori_handles,'YData', y);
                    set(app.dips(app.temp.dip_idx).ori_handles,'ZData', z);

                else     % vector dipole
                    ori_length = [1 1 1]*20;
                    if ~isempty(app.dips(didx).swf) && size(app.dips(didx).swf,1)==3
                        ori_length = rms(app.dips(didx).swf(:,app.model.fit_samps)*app.temp.convert_factor,2)*scale_fact;
                    end
                    xyz(:,:,1) = [0 1; 0 0; 0 0];
                    xyz(:,:,2) = [0 0; 0 1; 0 0];
                    xyz(:,:,3) = [0 0; 0 0; 0 1];
                    for p=1:size(app.dips(app.temp.dip_idx).ori_handles,2)
                        x = app.dips(didx).dip_pos(1)+squeeze(xyz(1,:,p))*ori_length(p);
                        y = app.dips(didx).dip_pos(2)+squeeze(xyz(2,:,p))*ori_length(p);
                        z = app.dips(didx).dip_pos(3)+squeeze(xyz(3,:,p))*ori_length(p);
                        set(app.dips(app.temp.dip_idx).ori_handles(p),'XData', x);
                        set(app.dips(app.temp.dip_idx).ori_handles(p),'YData', y);
                        set(app.dips(app.temp.dip_idx).ori_handles(p),'ZData', z);
                    end
                end

                if app.dips(app.temp.dip_idx).dip_on==0
                    set(app.dips(app.temp.dip_idx).mrk_handles(4), 'Visible','off')
                    set(app.dips(app.temp.dip_idx).ori_handles, 'Visible','off')
                else
                    set(app.dips(app.temp.dip_idx).mrk_handles(4), 'Visible','on')
                    set(app.dips(app.temp.dip_idx).ori_handles, 'Visible','on')
                end
                app.dips(app.temp.dip_idx).search_pos = app.dips(app.temp.dip_idx).dip_pos;

                %% Update symmetric dips
                sidx = find([app.dips.sym_idx] == app.dips(app.temp.dip_idx).num);
                if ~isempty(sidx)
                    for v=1:length(sidx)
                        %% update 2D
                        for a=1:3
                            app.dips(sidx(v)).dip_pos = app.dips(app.temp.dip_idx).dip_pos;
                            app.dips(sidx(v)).dip_pos(2) = app.dips(sidx(v)).dip_pos(2)*-1;
                            app.dips(sidx(v)).get_mri_pos(app.anatomy.mri.transform); % setting dip_pos in "dipole" object
                            set(app.dips(sidx(v)).mrk_handles(a),'XData', app.dips(sidx(v)).mri_pos(cx(a,1)));
                            set(app.dips(sidx(v)).mrk_handles(a),'YData', app.dips(sidx(v)).mri_pos(cx(a,2)));

                            if app.dips(sidx(v)).dip_on==0
                                set(app.dips(sidx(v)).mrk_handles(a), 'Visible','off')
                            else
                                set(app.dips(sidx(v)).mrk_handles(a), 'Visible','on')
                            end

                        end
                        % update 3D
                        set(app.dips(sidx(v)).mrk_handles(4),'XData', app.dips(sidx(v)).dip_pos(1));
                        set(app.dips(sidx(v)).mrk_handles(4),'YData', app.dips(sidx(v)).dip_pos(2));
                        set(app.dips(sidx(v)).mrk_handles(4),'ZData', app.dips(sidx(v)).dip_pos(3));
                        if app.dips(sidx(v)).dip_on==0
                            set(app.dips(sidx(v)).mrk_handles(a), 'Visible','off')
                        else
                            set(app.dips(sidx(v)).mrk_handles(a), 'Visible','on')
                        end
                        app.dips(sidx(v)).search_pos = app.dips(sidx(v)).dip_pos;
                    end
                end
            end

        end
        %% Delete Dips
        function del_dips(app, del_type)
            if ~isempty(app.listbox_dips.Items)
                switch del_type
                    case 'all'
                        app.dips = dipole(); % default
                        app.dips(1).num = nan; app.temp.dip_idx = [];
                        app.listbox_dips.Items = {''}; app.listbox_dips.Value = ''; app.listbox_dips.ValueIndex = 1;
                    case 'selected'

                        didx = setxor(1:size(app.dips,2), app.listbox_dips.ValueIndex);
                        if isempty(didx)
                            app.dips = dipole(); % default
                            app.dips(1).num = nan; app.temp.dip_idx = [];
                            app.listbox_dips.Items = {''}; app.listbox_dips.Value = ''; app.listbox_dips.ValueIndex = 1;
                        else
                            app.dips = app.dips(didx);
                        end

                end

                %% Renumbering
                for v=1:size(app.dips,2)
                    app.dips(v).num = v;
                    set(app.dips(v).mrk_handles, 'MarkerFaceColor', app.dips(v).dip_clr); 
                end
                % update_dips(app);
                
                app.temp.dip_idx = 1; 
                app.listbox_dips.ValueIndex = 1; 
                update_dip_info_panel(app);
                plot_mri(app);


            end
        end

        %% Main Fitting: "
        function fit_dipoles(app)


            %% Can only use "Spheres modeling" right now due to "ft_compute_leadfield.m" needs "tissue" boundaries
            % if ~contains(app.menu_headmodel.Value,'Spheres')
            %     app.menu_headmodel.Value = "Spheres(Volume)";
            %     SelectHeadModel(app); drawnow;
            % end
            %% update fit params
            update_fit_params(app);
            plot_params = [];


            for v=1:size(app.dips,2)
                app.dips(v).get_mri_pos(app.anatomy.mri.transform);
                app.dips(v).search_pos = app.dips(v).dip_pos;
            end
            update_dip_info_panel(app);

            %% Main fitting
            if app.check_autoUpdate.Value==1

                % reset ERP plot
                btn_clear_erp_plotPushed(app, []); plot_erp(app);

                % [fit_error, app.dips] = bl_dipole_fitting(app.data.avg_data, app.anatomy.leadfield, app.dips, app.fit_params, plot_params, app.check_iterations.Value, app.check_autoUpdate.Value);
                % [fit_error, app.dips] = bl_dipole_fitting(app.data.avg_data, app.dips, app.anatomy.lf_pos, app.anatomy.hdm_tripos, app.anatomy.sens, app.anatomy.ft_hdm, app.fit_params, plot_params, app.check_iterations.Value, app.check_autoUpdate.Value);
                app.fit_params.fitType = app.menu_search_grid.Value;
                [fit_error, app.dips] = bl_dipole_fitting(app.data.avg_data, app.dips, app.anatomy.leadfield, app.anatomy.hdm_tripos, app.anatomy.sens, app.anatomy.headmodel, app.fit_params, plot_params, app.check_iterations.Value, app.check_autoUpdate.Value);
                % switch app.menu_search_grid.Value
                %     case 'LeadField'
                %         [fit_error, app.dips] = bl_dipole_fitting_grid(app.data.avg_data, app.dips, app.anatomy.leadfield, app.anatomy.sens, app.anatomy.headmodel, app.fit_params, plot_params, app.check_iterations.Value, app.check_autoUpdate.Value);
                %     case {'Continuous' 'Multistage'}
                %         [fit_error, app.dips] = bl_dipole_fitting_ori(app.data.avg_data, app.dips, app.anatomy.hdm_tripos, app.anatomy.sens, app.anatomy.headmodel, app.fit_params, plot_params, app.check_iterations.Value, app.check_autoUpdate.Value);
                % end

                for v=1:size(app.dips,2); app.dips(v).get_mri_pos(app.anatomy.mri.transform); end
                % %% Calc source waves
                calc_swf(app); plot_model_waves(app);
                plot_swf(app);

                toggle_erp_data(app);

            elseif app.check_autoUpdate.Value==0 && app.temp.fitting_on == 1
                xdata = app.data.avg_data(app.model.fit_samps,:);
                % [fit_error, app.dips] = bl_dipole_fitting(app.data.avg_data, app.anatomy.leadfield, app.dips, app.fit_params, plot_params, app.check_iterations.Value, 0);
                % [fit_error, app.dips] = bl_dipole_fitting(xdata, app.dips, app.anatomy.lf_pos, app.anatomy.hdm_tripos, app.anatomy.sens, app.anatomy.ft_hdm, app.fit_params, plot_params, app.check_iterations.Value, 0);
                app.fit_params.fitType = app.menu_search_grid.Value;
                [fit_error, app.dips] = bl_dipole_fitting(xdata, app.dips, app.anatomy.leadfield, app.anatomy.hdm_tripos, app.anatomy.sens, app.anatomy.headmodel, app.fit_params, plot_params, app.check_iterations.Value, 0);
                % switch app.menu_search_grid.Value
                %     case 'LeadField'
                %         [fit_error, app.dips] = bl_dipole_fitting_grid(app.data.avg_data, app.dips, app.anatomy.leadfield, app.anatomy.sens, app.anatomy.headmodel, app.fit_params, plot_params, app.check_iterations.Value, 0);
                %     case 'Continuous'
                %         [fit_error, app.dips] = bl_dipole_fitting_ori(xdata, app.dips, app.anatomy.hdm_tripos, app.anatomy.sens, app.anatomy.headmodel, app.fit_params, plot_params, app.check_iterations.Value, 0);
                %     case 'Multistage' % This doesn't help improve speed
                %         [fit_error, app.dips] = bl_dipole_fitting_grid(xdata, app.dips, app.anatomy.leadfield, app.anatomy.sens, app.anatomy.headmodel, app.fit_params, plot_params, app.check_iterations.Value, 0);
                %         % Initial search = grid search
                %         % change search radi to be 5* lf grid step size
                %         lf_step = max(diff(app.h.anatomy.leadfield.voxel_pos(1:2,:)));
                %         for v=1:size(app.dips,2)
                %             search_rad(v) = app.dips(v).search_radius;
                %             app.dips(v).search_radius = 10*lf_step;
                %         end
                %         % Final Search = continuous search only within stepsize of leadfield grid
                %         [fit_error, app.dips] = bl_dipole_fitting_ori(xdata, app.dips, app.anatomy.hdm_tripos, app.anatomy.sens, app.anatomy.headmodel, app.fit_params, plot_params, app.check_iterations.Value, 0);
                %         for v=1:size(app.dips,2)
                %             app.dips(v).search_radius = search_rad(v);
                %         end
                % end

                for v=1:size(app.dips,2); app.dips(v).get_mri_pos(app.anatomy.mri.transform); end
                
                % %% Calc source waves
                calc_swf(app); plot_model_waves(app);
                for v=1:size(app.dips,2)
                    app.temp.dip_idx = v; update_mri_pos(app);
                end
            else
                % do nothing because dipoles are being moved on mri
            end
            drawnow;
        
            %% copy to undo
            app.temp.undo_counter = app.temp.undo_counter+1;
            if app.temp.undo_counter>10; app.temp.undo_counter = 1; end  % maximum of 10 undo
            % disp(app.temp.undo_counter)
            app.temp.undo(app.temp.undo_counter).dips = copy_dips(app);
            app.spinner_undo.Value = app.temp.undo_counter;

        end

        %% calc swf - calculate source waveforms
        function calc_swf(app)
            lf = [app.dips.leadfield];
            if ~isempty(lf)
                xdata = squeeze(mean(app.data.data,3)) ;
                swf  = xdata * pinv(lf'); % wts = pinv(leadfield)
                
                %% calc Residual Variance waves
                app.data.model_data = [lf*swf']';  % fitted sensor data
                app.data.residual_data = xdata-app.data.model_data;

                %% reshape swf
                % xswf = swf';
                dip_on = find([app.dips.dip_on]);
                % swf = permute(reshape(xswf, [3 length(dip_on) size(xswf,2) ] ),[2 1 3]); % struct = [dips x 3vectors x samps]
                % dims = size(swf);
                % app.data.swf = nan(size(app.dips,2), dims(2), dims(3));
                app.dips(dip_on).calc_swf(xdata);

                %% Residual Variance
                app.model.resid_var = sum(app.data.residual_data'.^2) ./ sum(xdata'.^2);
                plot_resid_var(app);
                plot_swf(app);
                plot_topo(app);
            else
                app.axes_dip_resid_var.Title.String = 'No Fit Solution Found with current parameters'; 
                app.axes_dip_resid_var.Title.Color = 'r';
                app.data.swf = [];
            end
            
        end
        function plot_resid_var(app)
            %% plot Residual variance
            rv_crit = 5;  % 5 percent 
            axis(app.axes_dip_resid_var,[app.data.lat([1 end]) 0 100]);
            app.axes_dip_resid_var.NextPlot ="replace";
            plot(app.axes_dip_resid_var, [0 0], app.axes_dip_resid_var.YLim,'--','Color',[1 1 1]*.6); % time point zero
            app.axes_dip_resid_var.NextPlot ="add";
            plot(app.axes_dip_resid_var, app.data.lat([1 end]), [rv_crit rv_crit],'--','Color',[1 1 1]*.6); % time point zero
            app.temp.h_RV = plot(app.axes_dip_resid_var, app.data.lat, squeeze(app.model.resid_var)*100,'r'); 
            app.axes_dip_resid_var.Title.String = sprintf('Residual Variance: Avg = %.1f%% (Best = %.1f%%)', mean(app.model.resid_var(app.model.fit_samps))*100, min(app.model.resid_var(app.model.fit_samps))*100 );
            app.axes_dip_resid_var.Title.Color = 'r';

        end
        %% Plot Source waves
        function plot_swf(app)
            % if ~isempty([app.dips.swf])
                app.axes_dip_waves.NextPlot = 'replace';
                yoffset = app.edit_yoffset_swf.Value; 
                vx_idx = fliplr(1:size(app.dips,2));
                for vv=1:length(vx_idx)
                    v = vx_idx(vv);
                    if app.dips(v).dip_on
                        if app.check_plot_rms.Value
                            swf = rms(squeeze(app.dips(v).swf),1);
                        else
                            swf = squeeze(app.dips(v).swf);
                        end
                        swf = swf* app.temp.convert_factor;
                        for d=1:size(swf,1)
                            % if ishandle(app.dips(v).wav_handles(d)); delete(app.dips(v).wav_handles(d)); end
                            app.dips(v).wav_handles(d) = plot(app.axes_dip_waves, app.data.lat, swf(d,:)+( (vv-1)*yoffset ),'Color',app.dips(v).dip_clr, 'ButtonDownFcn', {@app.drag_waveform_set}, 'Tag', sprintf('swf%.f',v));
                            app.axes_dip_waves.NextPlot = 'add';
                        end
                        
                    end
                end
                app.axes_dip_waves.Title.String = sprintf('Source Waves'); 
                % app.axes_dip_waves.YLim = [-yoffset yoffset*size(app.dips,2)]*1.2;
                app.edit_axes_swf_YLim1.Value = app.axes_dip_waves.YLim(1); 
                app.edit_axes_swf_YLim2.Value = app.axes_dip_waves.YLim(2); 
                app.slider_dip_waves_scale.Limits = app.axes_dip_waves.YLim;
                app.slider_dip_waves_scale.Value = app.axes_dip_waves.YLim;
                plot_time_int(app);
                %% reorder waves to be on top
                app.axes_dip_waves.Children = flipud(app.axes_dip_waves.Children); 

            % end
        end

        %% update model
        function update_model(app) % this is used to update the entire fit data when dipole is moved
            % %% get leadfields for current dipole
            % for v=1:size(app.dips,2)
            %     app.anatomy.leadfield.H(:,:,vidx);
            % end

            %% recalc model
            calc_swf(app); plot_model_waves(app);
        end
        %% Drag waveform - Set
        function drag_waveform_set(app, obj, ~)
            if ishandle(obj)
                app.SourceModeling.WindowButtonMotionFcn = {@app.drag_waveform_start, obj}; % start ginput to get cursor location on axes
                app.SourceModeling.WindowButtonUpFcn = {@app.drag_waveform_stop}; % start ginput to get cursor location on axes
                rotate3d(app.SourceModeling,"off"); 
                % if contains(obj.Type, 'line')
                %     app.temp.dip_idx = str2num(obj.Tag(4:end));
                %     app.listbox_dips.ValueIndex = app.temp.dip_idx;
                %     app.temp.mri_pos = app.dips(app.temp.dip_idx).mri_pos;
                %     update_dip_info_panel(app);
                %     update_mri_slices(app);
                % end
                %% Stop drag if btn remains stuck in "click" mode
                app.temp.tic = tic;
            end
        end
        %% Drag waveform - Start
        function drag_waveform_start(app, ~, ~, obj)
             if toc(app.temp.tic)>5     % 5 seconds
                stop_drag_obj(app,[],[]);
            else
                if ishandle(obj)
                    ax = obj.Parent;
                    cpt = get(ax,'CurrentPoint'); cpt = cpt(1,2);
                    app.temp.dip_idx = str2num(obj.Tag);
                    if cpt<ax.YLim(1); cpt=ax.YLim(1); 
                    elseif cpt>ax.YLim(2); cpt=ax.YLim(2); 
                    end
                    %% updating mri_pos for each Axes
                    if contains(obj.Type, 'line') % clicked dipole not MRI
                        yoffset = cpt - obj.YData(1);
                        obj.YData = obj.YData + yoffset;
                    end
                end

            end
        end

        %% Drag waveform - Stop
        function drag_waveform_stop(app, ~,~)
            app.SourceModeling.WindowButtonUpFcn = ''; app.SourceModeling.WindowButtonMotionFcn = ''; app.SourceModeling.WindowButtonDownFcn = '';
            app.edit_axes_swf_YLim1.Value = app.axes_dip_waves.YLim(1);
            app.edit_axes_swf_YLim2.Value = app.axes_dip_waves.YLim(2);
            app.slider_dip_waves_scale.Limits = app.axes_dip_waves.YLim;
            app.slider_dip_waves_scale.Value = app.axes_dip_waves.YLim;
            plot_time_int(app);

        end
        
   %% %%%%%%%%%%%%%%%%%%%%%%%%%%%%% MRI Functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Add Dipoles to params
        function add_dipoles(app,source,event)
            cpt = event.IntersectionPoint; % current point clicked on in an axes
            %% Get mouse click position on MRI axes
            get_mri_pos(app, source, cpt); 

            %% Add dipole as scatter point - when right click on MRI axes
            if event.Button==3
                %% next index to add new dipole
                if isempty(app.dips) 
                    didx = 1;
                else
                    if isnan(app.dips(1).mri_pos); didx = 1; else; didx = size(app.dips,2)+1; end
                end
                
                %% update params.dip_pos
                app.dips(didx) = dipole(didx, app.temp.mri_pos, app.anatomy.mri.transform); 
                app.dips(didx).dip_clr = app.temp.dip_clr(didx,:);  app.color_dip_clr.Value = app.dips(didx).dip_clr;
                %% plot new dipole
                event.Source.Parent.NextPlot = "add";
                app.dips(didx).mrk_handles(1) = scatter(app.Axes_Axial,   app.dips(didx).mri_pos([2]), app.dips(didx).mri_pos([1]),'filled','o','MarkerEdgeColor','k','MarkerFaceColor', app.dips(didx).dip_clr, 'ButtonDownFcn', {@app.set_drag_obj}, 'Tag', sprintf('%.f',didx));
                app.dips(didx).mrk_handles(2) = scatter(app.Axes_Sagital, app.dips(didx).mri_pos([3]), app.dips(didx).mri_pos([1]),'filled','o','MarkerEdgeColor','k','MarkerFaceColor', app.dips(didx).dip_clr, 'ButtonDownFcn', {@app.set_drag_obj}, 'Tag', sprintf('%.f',didx));
                app.dips(didx).mrk_handles(3) = scatter(app.Axes_Coronal, app.dips(didx).mri_pos([3]), app.dips(didx).mri_pos([2]),'filled','o','MarkerEdgeColor','k','MarkerFaceColor', app.dips(didx).dip_clr, 'ButtonDownFcn', {@app.set_drag_obj}, 'Tag', sprintf('%.f',didx));
                for a=1:3; app.temp.selected_dip(a) = app.dips(didx).mrk_handles(a); end
                app.temp.dip_idx = didx;
                update_dip_info_panel(app);
                plot_mri(app);
            else    %% change MRI images
                update_mri_slices(app); % update mri
                set_drag_obj(app, source);
            end


        end
        %% Set Obj drag function
        function set_drag_obj(app, obj, ~)
            if isvalid(obj)
                app.SourceModeling.WindowButtonMotionFcn = {@app.start_drag_obj, obj}; % start ginput to get cursor location on axes
                app.SourceModeling.WindowButtonUpFcn = {@app.stop_drag_obj}; % start ginput to get cursor location on axes
                rotate3d(app.SourceModeling,"off"); 
                update_dip_info_panel(app);
                if isprop(obj, 'Marker')   % clicked dipole not MRI
                    app.listbox_dips.ValueIndex = str2num(get(obj,'Tag')); app.temp.dip_idx = app.listbox_dips.ValueIndex;
                    app.temp.mri_pos = app.dips(app.temp.dip_idx).mri_pos;
                end
                %% Stop drag if btn remains stuck in "click" mode
                app.temp.tic = tic;
            end
        end
        %% Start dragging selected obj
        function start_drag_obj(app, ~, ~, obj)
            if toc(app.temp.tic)>5      % 20 seconds
                stop_drag_obj(app,[],[]);
            else
                if isvalid(obj)
                    ax = obj.Parent;
                    cpt = get(ax,'CurrentPoint');
                    app.temp.dip_idx = str2num(obj.Tag);
                    get_mri_pos(app, obj, cpt);
                    %% updating mri_pos for each Axes
                    if isprop(obj, 'Marker')   % clicked dipole not MRI

                        %% Set dip_pos
                        app.dips(app.temp.dip_idx).mri_pos = app.temp.mri_pos;
                        app.dips(app.temp.dip_idx).get_dip_pos(app.anatomy.mri.transform); % setting dip_pos in "dipole" object

                        %% Update dip_pos in Dip Info Panel
                        app.edit_dip_pos.Value = sprintf('%.1f %.1f %.1f', app.dips(app.temp.dip_idx).dip_pos); app.edit_dip_pos.FontColor = app.dips(app.temp.dip_idx).dip_clr;
                        app.edit_dip_ori.Value = sprintf('%.f %.f', app.dips(app.temp.dip_idx).dip_ori); app.edit_dip_ori.FontColor = app.dips(app.temp.dip_idx).dip_clr;

                        update_mri_pos(app); % update dipole's mri_pos on MRIs

                    end
                    update_mri_slices(app); % update mri
                end

            end
        end

        %% Stop dragging selected obj
        function stop_drag_obj(app, ~,~)
            app.SourceModeling.WindowButtonUpFcn = ''; app.SourceModeling.WindowButtonMotionFcn = ''; app.SourceModeling.WindowButtonDownFcn = '';
            if app.check_autoUpdate.Value==1
                fit_dipoles(app);
            end
            % app.check_autoUpdate.Value = app.temp.autoUpdate_val;
            update_dip_info_panel(app)
            plot_mri(app);
        end
        %% Get MRI position
        function get_mri_pos(app, source, cpt)
            %% Update MRI pos
            switch source.Parent.Title.String
                case 'Axial'
                    app.temp.mri_pos([2 1]) = cpt(1,1:2);
                case 'Sagital'
                    app.temp.mri_pos([3 1]) = cpt(1,1:2);
                case 'Coronal'
                    app.temp.mri_pos([3 2]) = cpt(1,1:2);
            end
            %% Keeping within MRI image bounds
            dims = size(app.anatomy.mri.anatomy);
            app.temp.mri_pos(app.temp.mri_pos<=0) = 1;
            app.temp.mri_pos(app.temp.mri_pos>dims) = dims(app.temp.mri_pos>dims); % keeping mri_pos within image size
            
            app.temp.mri_pos = round(app.temp.mri_pos);

        end
        %% Update MRI slices
        function update_mri_slices(app)
            dims = size(app.anatomy.mri.anatomy);
            app.temp.mri_pos(app.temp.mri_pos<=0) = 1;
            app.temp.mri_pos(app.temp.mri_pos>dims) = dims(app.temp.mri_pos>dims); % keeping mri_pos within image size
            %% Updating MRI slice
            app.temp.h_2Dimg(1).img.CData = squeeze(app.anatomy.mri.anatomy(:,:,app.temp.mri_pos(3))); % Axial
            app.temp.h_2Dimg(2).img.CData = squeeze(app.anatomy.mri.anatomy(:,app.temp.mri_pos(2),:)); % Sagital
            app.temp.h_2Dimg(3).img.CData = squeeze(app.anatomy.mri.anatomy(app.temp.mri_pos(1),:,:)); % Coronal
            drawnow;
        end

        function plot_hdm(app)
            if isempty(app.temp.himg_3Dhdm)
                if contains(app.menu_headmodel.Value,'Spheres')
                    % app.temp.himg_3Dhdm = patch(app.Axes_3D, 'Faces', app.anatomy.hdm_tripos.tri,'Vertices', app.anatomy.hdm_tripos.pos,'FaceColor','b','FaceAlpha',.3,'EdgeColor','none');
                    [x,y,z] = sphere; rad = max(app.anatomy.ft_hdm.r);
                    x=x*rad; y=y*rad; z=z*rad; 
                    x=x+app.anatomy.ft_hdm.o(1);  y=y+app.anatomy.ft_hdm.o(2);   z=z+app.anatomy.ft_hdm.o(3); 
                    app.temp.himg_3Dhdm = surf(app.Axes_3D, x, y, z,'EdgeColor','b','EdgeAlpha',.3,'FaceColor','b','FaceAlpha',.2);
                else
                    pos = app.anatomy.leadfield.pos;
                    app.temp.himg_3Dhdm = scatter3(app.Axes_3D, pos(:,1), pos(:,2), pos(:,3), 'o','MarkerFaceColor',[0 0 1],'MarkerEdgeColor','none','MarkerFaceAlpha',0.5, 'SizeData',2);
                end
                app.temp.himg_3Dhdm.Visible = 'off';
            end
            if ~isempty(app.temp.himg_3Dhdm) && app.btn_plot_headmodel.Value==1
                app.temp.himg_3Dhdm.Visible = 'on';
            else
                app.temp.himg_3Dhdm.Visible = 'off';
            end

        end
        
        function update_erp_panel(app)

             %% check sensor numbers
                if length(app.anatomy.sens.label)==length(app.data.sens_label)
                    for v=1:size(app.data.sens_label,1); gidx(v) = any(strcmpi(app.data.sens_label(v), app.anatomy.sens.label)); end
                    if all(gidx)
                    else
                        warndlg(sprintf('DataSet Sensors (n=%.f) not same as Head Model Sensor (n=%.f)',length(app.anatomy.sens.label), length(app.data.sens_label)),'Sensors Not Same')
                        app.data.data = []; return
                    end
                else
                    warndlg(sprintf('DataSet Sensors (n=%.f) not same as Head Model Sensor (n=%.f)',length(app.anatomy.sens.label), length(app.data.sens_label)),'Sensors Not Same')
                    app.data.data = []; return
                end
                app.data.data_org = app.data.data;
                % set avg_data;
                app.data.avg_data = squeeze(mean(app.data.data,3));


                %% add sensor to listbox
                % vis = 'off';
                if ~isempty(app.data.data)
                    app.listbox_sensors.Items = app.data.sens_label;
                    if size(app.data.data,3)==1
                    else
                        % vis = 'on';
                        app.slider_trial.Limits = [1 size(app.data.data,3)];
                    end
                end
                % app.slider_trial.Visible = vis; app.btn_trial_next.Visible = vis; app.btn_prev_trial.Visible = vis;
                app.model.fit_int = [0 0]; %% ERP panel time interval
                app.listbox_sensors.ValueIndex = 1:length(app.listbox_sensors.Items);
                %% clear Resid Var
                app.model.resid_var = [];
                %% plot ERP
                btn_clear_erp_plotPushed(app, [])
                plot_erp(app); 
        end
        
        function cp_dips = copy_dips(app)

            %% copy dips to new cp_dips
            for v=1:size(app.dips,2)
                cp_dips(v) = dipole(v, app.dips(v).mri_pos, app.anatomy.mri.transform);
                fn = fieldnames(cp_dips(1));
                fn = fn(~contains(fn,'handles'));
                for f=1:length(fn)
                    cp_dips(v).(fn{f}) = app.dips(v).(fn{f});
                end
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, h, anatomy, data, lat, srate, sens_label)
            % data struct = .data [chans x samps x trials], .lat, .srate, .sens_label
            %% Default variables
            %% Addpath BRANELab "mfiles"
            % try addpath(fullfile(pwd),"mfiles"); catch; fprintf('WARNING! Supporting "mfiles" folder not in current directory: %s\n',pwd); end

            %% Addpath "FieldTrip"
            cpwd = pwd;
            x = which('ft_warp_apply');
            if isempty(x)
                cd C:\BRANELab\Software\SimMEEG\fieldtrip-20240201;
                ft_defaults;
                cd(cpwd);
            end


            %% Initialize Variables
            % .data
            app.model.resid_var = [];
            % .dip
            app.dips = dipole(); % default
            % .erp
            app.model.fit_int = [0 0]; app.model.fit_samps = []; 
            app.h_plots.h_overlay_data = []; %app.h_plots.h_trial_data = [];
            app.h_plots.h_overlay_model = [];
            app.h_plots.h_overlay_residual = [];
            app.model.fit_int_plot_overlay = [];
            % misc
            app.edit_fit_interval.Value = sprintf('%.3f %.3f', app.model.fit_int);
            app.fit_params  = dipfit_params();
            app.temp.dip_clr = lines(120); app.temp.dip_clr(1:6,:) = [ 0 .6 0; 0 0 1; 1 0 0;1 .5 0; 1 0 1; 0 1 1];
            app.temp.mri_pos = [1 1 1]; app.temp.dip_idx = [];
            % app.menu_search_type.Items = {'fmincon' 'fminmax' 'Pattern' 'ParticleSwarm' 'Pareto' 'fminsearch' 'Genetic' 'GeneticMulti' 'SimulatedAnnealing'};
            app.menu_search_type.Items = {'Pattern' 'fminsearch' 'ParticleSwarm' 'Pareto' 'Genetic' 'GeneticMulti' 'SimulatedAnnealing'};
            app.temp.fitting_on = 0;
            app.temp.himg_3Dhdm = [];
            app.temp.convert_factor = 1; % conversion factor for calculating source waveforms based on FieldTrip's leadfields
            app.temp.soln_name = fullfile(pwd, 'bl_dip_soln1.mat');
            app.model.solns(1).Name = 'Soln 1'; 
            app.temp.soln_num = 1; 
            app.temp.undo(1).dips = []; 
            app.temp.undo_counter = 0; 
            app.data.data = []; app.data.avg_data =[]; app.data.model_data =[];  app.data.residual_data = []; 
            %% Combining MRI Axes
            app.Axes_2D(1).h = app.Axes_Axial; app.Axes_2D(2).h = app.Axes_Sagital; app.Axes_2D(3).h = app.Axes_Coronal;
            app.temp.h_topo = [];
            %% Inputted Variables
            if nargin==2
                if ~isempty(h.anatomy) % called from SimMEEG
                    app.h = h;
                    app.anatomy = h.anatomy; % loaded from
                    app.data.data = h.sim_data.sens_final; %erp_data;
                    app.data.lat = h.cfg.study.lat_sim;
                    app.data.srate = h.cfg.study.srate;
                    app.data.sens_label = h.anatomy.sens.label;
                    app.temp.mri_pos = round(size(app.anatomy.mri.anatomy)/2);
                    

                    %% update sens_type ...
                    fn = fieldnames(app.anatomy);
                    sens_types = {''};
                    if any(contains(fn, 'sens_eeg'))
                        sens_types = [sens_types {'EEG'}];
                    end
                    if any(contains(fn, 'sens_meg'))
                        sens_types = [sens_types {'MEG'}];
                    end
                    sens_types = sens_types(cellfun(@isempty,sens_types)==0);
                    app.menu_sensor_type.Items = sens_types;
                    if strcmpi(app.h.anatomy.sens.type,'meg')
                        app.menu_sensor_type.Value = 'MEG';
                    else
                        app.menu_sensor_type.Value = 'EEG';
                    end

                    %% plot MRI
                    update_anatomy_fields(app);
                    plot_mri(app);

                    %% updating headmodel
                    menu_sensor_typeValueChanged(app, []);
                    app.temp.himg_3Dhdm = [];
                    app.menu_num_sensors.Value = num2str(size(app.h.anatomy.leadfield.H,1));

                    SelectHeadModel(app);
                    figure(app.SourceModeling);
                    app.temp.mri_pos = round(size(app.anatomy.mri.anatomy)/2);
                    %% update & plot ERP
                    update_erp_panel(app);
                end
            elseif nargin>2 %% load from command line
                app.btn_export_SimMEEG.Visible = 'off';

                app.anatomy = anatomy; % loaded from
                app.data.data = data; %erp_data;
                app.data.lat = lat;
                app.data.srate = srate;
                app.data.sens_label = sens_label;
                app.temp.mri_pos = round(size(app.anatomy.mri.anatomy)/2);


                %% update sens_type ...
                fn = fieldnames(app.anatomy);
                sens_types = {''};
                if any(contains(fn, 'sens_eeg'))
                    sens_types = [sens_types {'EEG'}];
                end
                if any(contains(fn, 'sens_meg'))
                    sens_types = [sens_types {'MEG'}];
                end
                sens_types = sens_types(cellfun(@isempty,sens_types)==0);
                app.menu_sensor_type.Items = sens_types;
                if strcmpi(app.anatomy.sens.type,'meg')
                    app.menu_sensor_type.Value = 'MEG';
                else
                    app.menu_sensor_type.Value = 'EEG';
                end

                %% plot MRI
                % update_anatomy_fields(app);
                plot_mri(app);

                %% updating headmodel
                menu_sensor_typeValueChanged(app, []);
                app.temp.himg_3Dhdm = [];
                app.menu_num_sensors.Value = num2str(size(app.anatomy.leadfield.H,1));

                SelectHeadModel(app);
                figure(app.SourceModeling);
                app.temp.mri_pos = round(size(app.anatomy.mri.anatomy)/2);
                %% update & plot ERP
                update_erp_panel(app);

            end


        end

        % Callback function: LoadAnatomyMenu, btn_load_anatomy
        function btn_load_anatomyPushed(app, event)
            app.lamp_run.Color = 'g'; drawnow
            [fname,fpath] = uigetfile('');
            fprintf('Loading Anatomy File: %s\n',fullfile(fpath, fname));
            figure(app.SourceModeling);
            if all(fname~=0)
                app.anatomy = load(fullfile(fpath, fname));
                fn = fieldnames(app.anatomy);
                sens_types = {''};
                if any(contains(fn, 'sens_eeg'))
                    sens_types = [sens_types {'EEG'}];
                end
                if any(contains(fn, 'sens_meg'))
                    sens_types = [sens_types {'MEG'}];
                end
                sens_types = sens_types(cellfun(@isempty,sens_types)==0);
                app.menu_sensor_type.Items = sens_types;
            end
            app.lamp_run.Color = 'r'; drawnow


            %% updating headmodel
            menu_sensor_typeValueChanged(app, []);
            app.temp.himg_3Dhdm = []; 
            SelectHeadModel(app);
            figure(app.SourceModeling);
            app.temp.mri_pos = round(size(app.anatomy.mri.anatomy)/2);

            %% converting to "mm"
            app.anatomy.mri = ft_convert_units(app.anatomy.mri,'mm');
            app.anatomy.leadfield = ft_convert_units(app.anatomy.leadfield,'mm');
            app.anatomy.headmodel = ft_convert_units(app.anatomy.headmodel,'mm');
            app.anatomy.mesh_volumes = ft_convert_units(app.anatomy.mesh_volumes,'mm');
            plot_mri(app);
            app.check_radiological_view.Value = 0; 
            update_mri_axes(app);

            fprintf('Done\n');


        end

        % Callback function: LoadERPMenu, btn_load_erp
        function btn_load_erpPushed(app, event)
           
            [fname,fpath] = uigetfile('');
            if all(fname==0)

            else
                app.temp.erp_name = fullfile(fpath, fname);
                [~, xname] = fileparts(app.temp.erp_name);
                app.temp.soln_name = fullfile(fpath, sprintf('%s_soln1.mat',xname));
                app.data = load(app.temp.erp_name);
                app.data.avg_data =[]; app.data.model_data =[];  app.data.residual_data = []; 
                update_erp_panel(app);
               
            end
            figure(app.SourceModeling); 
        end

        % Value changed function: menu_headmodel
        function menu_headmodelValueChanged(app, event)
            SelectHeadModel(app);
            if ~isempty(app.temp.himg_3Dhdm)
                if isvalid(app.temp.himg_3Dhdm); delete(app.temp.himg_3Dhdm); end
                app.temp.himg_3Dhdm = [];
            end
            plot_hdm(app);
        end

        % Value changed function: menu_num_sensors
        function menu_num_sensorsValueChanged(app, event)
            SelectHeadModel(app);

        end

        % Value changed function: menu_sensor_type
        function menu_sensor_typeValueChanged(app, event)
            sens_num = {''};
            if contains(app.menu_sensor_type.Value,'EEG')
                for v=1:size(app.anatomy.sens_eeg,2)
                    sens_num(v) = {num2str(length(app.anatomy.sens_eeg(v).label))};
                end
            end
            if contains(app.menu_sensor_type.Value,'MEG')
                for v=1:size(app.anatomy.sens_meg,2)
                    sens_num(v) = {num2str(length(app.anatomy.sens_meg(v).label))};
                end
            end
            sens_num = sens_num(cellfun(@isempty,sens_num)==0);
            app.menu_num_sensors.Items = sens_num;
            SelectHeadModel(app);

        end

        % Button pushed function: btn_plot_mri
        function btn_plot_mriPushed(app, event)
            plot_mri(app);
        end

        % Value changed function: slider_brain_transparency, 
        % ...and 3 other components
        function slider_transparencyValueChanged(app, event)
            for a=1:3
                set(app.temp.h_2Dimg(a).img ,'AlphaData',app.slider_mri_transparency.Value/100);
            end
            set(app.temp.himg_3Dmesh(1),'FaceAlpha',app.slider_scalp_transparency.Value/100);    % scalp
            set(app.temp.himg_3Dmesh(2),'FaceAlpha',app.slider_brain_transparency.Value/100);    % brain/cortex
            % Need to add topo map to app.temp.himg_3Dmesh(3)
            if ~isempty(app.temp.h_topo)
                if isvalid(app.temp.h_topo)
                set(app.temp.h_topo,'FaceAlpha',app.slider_topo_transparency.Value/100);
                end
            end
        end

        % Button pushed function: btn_plot_erp
        function btn_plot_erpButtonPushed(app, event)
            plot_erp(app,event);

        end

        % Clicked callback: listbox_sensors
        function listbox_sensorsClicked(app, event)
            btn_plot_erpButtonPushed(app, event);

        end

        % Button down function: axes_erp_waves
        function axes_erp_wavesButtonDown(app, event)
            click_drag_interval(app,event)
        end

        % Value changed function: edit_fit_interval
        function edit_fit_intervalValueChanged(app, event)
            value = app.edit_fit_interval.Value;
            app.model.fit_int = str2num(value);
            plot_erp(app,event);
        end

        % Value changed function: check_dip_search_radius
        function check_dip_search_radiusValueChanged(app, event)
            value = app.check_dip_search_radius.Value;
            if value == 1
                app.edit_dip_search_radius.Enable = "on";
            else
                app.edit_dip_search_radius.Enable = "off";
            end
            if all(~isnan(app.dips(1).mri_pos)); update_dipsCallback(app, event); end

        end

        % Button pushed function: btn_start_fit
        function btn_start_fitPushed(app, event)
            sel_dip = app.listbox_dips.ValueIndex; 
            %% get ERP data within selected interval
            if isempty(app.model.fit_samps); warndlg('Please select fit interval in ERP window','No Fit Sample!'); return; end
            tic;
            %% Dipole Fit data
            app.lamp_run.Color = 'g'; drawnow
            app.temp.fitting_on = 1;
            preval = app.check_autoUpdate.Value;
            app.check_autoUpdate.Value = 0;

            %% push dips out by 5 mm of valley to start
            % pos_offset = randi([0 50],1,3)/10;
            % for v=1:size(app.dips,2); app.dips(v).dip_pos = app.dips(v).dip_pos+pos_offset; app.dips(v).get_mri_pos(app.anatomy.mri.transform); end

            fit_dipoles(app);

            %% update plots
            app.listbox_dips.ValueIndex = sel_dip;
            app.temp.dip_idx = sel_dip;
            listbox_dipsDoubleClicked(app, []);
            update_dip_info_panel(app);

            app.check_autoUpdate.Value = preval;
            app.temp.fitting_on = 0;
            app.lamp_run.Color = 'r'; 
            app.text_RunTime.Text = sprintf("Run Time = %.1f sec",toc);
            drawnow

        end

        % Callback function
        function btn_undoValueChanged(app, event)
            if app.btn_undo.Value ==1
                app.temp.undo_counter = app.temp.undo_counter+1;
                app.btn_undo.Text = 'redo';
            else
                app.temp.undo_counter = app.temp.undo_counter-1;
                app.btn_undo.Text = 'undo';
            end
            app.dips = app.temp.undo(app.temp.undo_counter).dips;
            app.temp.dip_idx = app.listbox_dips.ValueIndex; 
            listbox_dipsDoubleClicked(app, []);
            update_dip_info_panel(app);
            update_mri_pos(app);
        end

        % Value changed function: check_dip_vect
        function check_dip_vectValueChanged(app, event)
            value = app.check_dip_vect.Value;
            if value==1
                app.edit_dip_ori.Visible = "off";
                app.menu_dip_ori_fit.Enable = "off";
                app.menu_dip_ori_fit.Value = "fixed";
            else
                app.edit_dip_ori.Visible = "on";
                app.menu_dip_ori_fit.Enable = "on";
                app.menu_dip_ori_fit.Value = "free";
            end
            update_dips(app)
        end

        % Value changed function: switch_dip_on
        function switch_dip_onValueChanged(app, event)
            value = app.switch_dip_on.Value;
            if strcmpi(value,"On")  % fit_on = true
                app.switch_dip_fit.Enable = "on"; app.switch_dip_fit.Value = "Fit"; app.switch_dip_fit.FontColor = [0 .6 0];
                app.edit_dip_order.Enable = "on"; if app.edit_dip_order.Value==0; app.edit_dip_order.Value=1; end
                app.switch_dip_on.FontColor = [0 .6 0];
            else
                app.switch_dip_fit.Enable = "off"; app.switch_dip_fit.Value = "No Fit"; app.switch_dip_fit.FontColor = [1 0 0];
                app.edit_dip_order.Enable = "off"; 
                app.switch_dip_on.FontColor = [1 0 0];
            end
            update_dips(app);
            fit_dipoles(app);
      
        end

        % Button down function: axes_dip_waves
        function axes_dip_wavesButtonDown(app, event)
            click_drag_interval(app,event)
        end

        % Value changed function: switch_dip_fit
        function switch_dip_fitValueChanged(app, event)
            value = app.switch_dip_fit.Value;
            if strcmpi(value,'Fit')
                app.switch_dip_fit.Value = "Fit"; app.switch_dip_fit.FontColor = [0 .6 0];
            else
                app.switch_dip_fit.Value = "No Fit"; app.switch_dip_fit.FontColor = [1 0 0];
            end
            update_dips(app);
        end

        % Value changed function: menu_dip_loc_fit
        function menu_dip_loc_fitValueChanged(app, event)
            value = app.menu_dip_loc_fit.Value;
            % defaults
            app.edit_dip_search_radius.Value = 200;
            if strcmpi(value, 'symmetric')
                app.menu_dip_symmetrical.Enable = "on";
                app.check_dip_sym_dist.Enable = "on";
                app.edit_dip_sym_dist.Enable = "on";
                app.check_dip_sym_dist.Enable = "on";
                %% allowable symmetric dipoles
                app.dips(app.temp.dip_idx).sym_type = value;
                update_dip_info_panel(app);
            elseif strcmpi(value, 'fixed')
                app.check_dip_search_radius.Value = 1; 
                app.edit_dip_search_radius.Value = 0; 
            else
                app.menu_dip_symmetrical.Enable = "off";
                app.check_dip_sym_dist.Value = 0; app.check_dip_sym_dist.Enable = "off";

            end
            update_dips(app);
        end

        % Callback function: listbox_dips, listbox_dips
        function listbox_dipsClicked(app, event)
            % item = event.InteractionInformation.Item;
            app.temp.dip_idx = app.listbox_dips.ValueIndex;
            update_dip_info_panel(app);

        end

        % Double-clicked callback: listbox_dips
        function listbox_dipsDoubleClicked(app, event)
            % item = event.InteractionInformation.Item;
            % app.lamp_run.Color = 'w';
            app.temp.mri_pos = app.dips(app.temp.dip_idx).mri_pos;
            update_mri_slices(app);
             % app.lamp_run.Color = 'g';
            % app.lamp_run.Color = 'k';
            

        end

        % Button pushed function: btn_dip_delete, btn_dip_delete_all
        function btn_dip_deletePushed(app, event)
            if strcmpi(event.Source.Text, 'Delete All')
                del_type = 'all';
            else
                del_type = 'selected';
            end
            del_dips(app, del_type);

        end

        % Value changed function: check_dip_sym_dist, color_dip_clr, 
        % ...and 6 other components
        function update_dipsCallback(app, event)
            if ~isempty(app.dips(app.temp.dip_idx))
                if all(~isnan(app.dips(app.temp.dip_idx).mri_pos))
                    app.dips(app.temp.dip_idx).dip_pos = str2num(app.edit_dip_pos.Value);
                    app.dips(app.temp.dip_idx).get_mri_pos(app.anatomy.mri.transform);
                    update_dips(app);
                    %% Search Radius
                    if app.check_dip_search_radius.Value==0
                        app.edit_dip_search_radius.Value = abs(app.dips(app.temp.dip_idx).search_radius)*-1;
                    else
                        app.edit_dip_search_radius.Value = abs(app.dips(app.temp.dip_idx).search_radius);
                    end
                    %% Sym Min Dist
                    if app.check_dip_search_radius.Value==0
                        app.edit_dip_sym_dist.Value = abs(app.dips(app.temp.dip_idx).sym_dist)*-1;
                    else
                        app.edit_dip_sym_dist.Value = abs(app.dips(app.temp.dip_idx).sym_dist);
                    end
                end
            end
        end

        % Value changed function: menu_dip_ori_fit
        function menu_dip_ori_fitValueChanged(app, event)
            value = app.menu_dip_ori_fit.Value;
            if strcmpi(value, 'free')
                app.check_dip_vect.Value = 0;
            % else
            %     app.check_dip_vect.Value = 1;
            end
            update_dips(app);
        end

        % Value changed function: check_radiological_view
        function check_radiological_viewValueChanged(app, event)
            update_mri_axes(app);
            
        end

        % Button pushed function: btn_dip_add
        function btn_dip_addButtonPushed(app, event)
            if ~isempty(app.anatomy)
                source = app.temp.h_2Dimg(1).img ;
                evt.IntersectionPoint  = round(size(app.anatomy.mri.anatomy)/2);
                evt.Button = 3;
                evt.Source = source;

                add_dipoles(app,source,evt)
            end

        end

        % Value changed function: btn_reref
        function btn_rerefValueChanged(app, event)
            value = app.btn_reref.Value;
            if value==1
                ref_idx = app.listbox_sensors.ValueIndex;
                ex_idx = setxor(1:size(app.data.data_org,2), ref_idx);
                app.data.data = bl_reref(app.data.data_org,ref_idx, ex_idx,1);
                app.btn_reref.FontColor = [0 .6 0];
                if isscalar(ref_idx)
                    app.btn_reref.Text = sprintf('Ref: %s', app.data.sens_label{ref_idx});
                elseif length(ref_idx)>1 && length(ref_idx)<size(app.data.data_org,2)
                    app.btn_reref.Text = sprintf('Ref: ...');
                elseif length(ref_idx)==size(app.data.data_org,2)
                    app.btn_reref.Text = sprintf('Ref: Avg');
                end

            else
                app.data.data = app.data.data_org;
                app.btn_reref.Text = 'No ref'; app.btn_reref.FontColor = 'k';
            end
            app.data.avg_data = mean(app.data.data,3);
            app.axes_erp_waves.clo; app.axes_trial_waves.clo;
            app.h_plots.h_overlay_data = [];
            btn_clear_erp_plotPushed(app,[]); plot_erp(app);
        end

        % Value changed function: toggle_view_data, toggle_view_model, 
        % ...and 1 other component
        function toggle_view_ValueChanged(app, event)
            toggle_erp_data(app);
             

        end

        % Value changed function: slider_dip_waves_scale
        function slider_dip_waves_scaleValueChanging(app, event)
            changingValue = event.Value;
            
            % ylim(1) = (app.edit_axes_swf_YLim2.Value/100)*changingValue(1);
            % ylim(2) = (app.edit_axes_swf_YLim2.Value/100)*changingValue(2);

            app.axes_dip_waves.YLim = changingValue; %ylim; 

        end

        % Value changed function: edit_axes_swf_YLim1, edit_axes_swf_YLim2
        function edit_axes_swf_YLim(app, event)
            app.axes_dip_waves.YLim = [app.edit_axes_swf_YLim1.Value app.edit_axes_swf_YLim2.Value];
            app.slider_dip_waves_scale.Limits = [app.edit_axes_swf_YLim1.Value app.edit_axes_swf_YLim2.Value];
            app.slider_dip_waves_scale.Value = [app.edit_axes_swf_YLim1.Value app.edit_axes_swf_YLim2.Value];

        end

        % Button pushed function: btn_clear_erp_plot
        function btn_clear_erp_plotPushed(app, event)
                app.axes_erp_waves.clo; app.axes_trial_waves.clo;
                app.h_plots.h_overlay_data = [];

        end

        % Value changed function: edit_yoffset_swf
        function edit_yoffset_swfValueChanged(app, event)
            value = app.edit_yoffset_swf.Value;
            plot_swf(app);
        end

        % Value changed function: btn_plot_headmodel
        function btn_plot_headmodelValueChanged(app, event)
            value = app.btn_plot_headmodel.Value;
            plot_hdm(app);
        end

        % Callback function: SaveSolutionMenu, btn_save_dip_soln
        function btn_save_dip_solnButtonPushed(app, event)
            
            [fname, app.temp.soln_path] = uiputfile('*.mat', 'Save Dipole Solution',app.temp.soln_name);
            if fname~=0
                app.temp.soln_name = fullfile(app.temp.soln_path, fname);
                dips = app.dips;
                save(app.temp.soln_name,'dips');
            end
            figure(app.SourceModeling);
        end

        % Button pushed function: btn_save_all_dip_soln
        function btn_save_all_dip_solnButtonPushed(app, event)
            
            [fpath, fname, fext]=fileparts(app.temp.erp_name);
            for sx=1:size(app.model.solns,2)
                sname = fullfile(fpath, sprintf('%s_soln%.f.mat',fname,sx));
                dips = app.model.solns(sx).dips;
                save(sname,'dips');
            end


        end

        % Callback function: LoadSolutionMenu, btn_load_dip_soln
        function btn_load_dip_solnButtonPushed(app, event)
            [fname, app.temp.soln_path] = uigetfile('*.mat','Load Dipole Solution',app.temp.soln_name);
            if fname~=0
                app.temp.soln_name = fullfile(app.temp.soln_path, fname);
                load(app.temp.soln_name,'dips');
                % This stores current soln then adds a new one
                if ~isempty(app.model.solns) % if solns exist
                    btn_new_dip_solnButtonPushed(app, []);
                end
                for v=1:size(dips,2)
                    dips(v).mrk_handles = [];
                    dips(v).ori_handles = [];
                    dips(v).wav_handles = [];
                end

                app.dips = dips;

                update_dip_info_panel(app);
                plot_mri(app);
            end
            figure(app.SourceModeling);
        end

        % Button pushed function: btn_invert_dip
        function btn_invert_dipButtonPushed(app, event)
            dp = deg2rad(app.dips(app.temp.dip_idx).dip_ori); [x, y, z] = sph2cart(dp(1), dp(2),1); xyz = [x y z]*-1;
            [az, el] = cart2sph(xyz(1), xyz(2), xyz(3)); ori = rad2deg([az el]);
            app.dips(app.temp.dip_idx).dip_ori = ori;
            app.dips(app.temp.dip_idx).swf = -app.dips(app.temp.dip_idx).swf;
            for d=1:size(app.dips(app.temp.dip_idx).wav_handles,2)
                if ishandle(app.dips(app.temp.dip_idx).wav_handles(d))
                set(app.dips(app.temp.dip_idx).wav_handles(d),'YData', app.dips(app.temp.dip_idx).swf(d,:));
                end
            end
            update_dip_info_panel(app);
            plot_mri(app);
        end

        % Button pushed function: btn_new_dip_soln
        function btn_new_dip_solnButtonPushed(app, event)
            %% store current model
            if ~isempty(app.temp.soln_num)
                app.model.solns(app.temp.soln_num).Name = sprintf('Soln %.f',app.temp.soln_num);
                app.model.solns(app.temp.soln_num).dips = app.dips;
                app.model.solns(app.temp.soln_num).fit_int = app.model.fit_int;
                app.model.solns(app.temp.soln_num).fit_samps = app.model.fit_samps;
                app.model.solns(app.temp.soln_num).resid_var = app.model.resid_var;
            else
                app.temp.soln_num = 0;
            end

            %% add new soln to model
            app.temp.soln_num = size(app.model.solns,2)+1;
            app.model.solns(app.temp.soln_num).Name = sprintf('Soln %.f',app.temp.soln_num);
            % if contains(event.Source.Text,'New')
                del_dips(app, 'all');
            % else
               % dips = copy(app.dips);
            % end
            app.model.solns(app.temp.soln_num).dips = app.dips;
            app.menu_dip_soln.Items = {app.model.solns.Name};  app.menu_dip_soln.ValueIndex = app.temp.soln_num; 
            %% clear undo && spinner
            app.temp.undo = [];
            app.temp.undo_counter = 1; 
            btn_dip_addButtonPushed(app, []);
            app.temp.undo(app.temp.undo_counter).dips = copy_dips(app);
            app.spinner_undo.Value = app.temp.undo_counter; 

        end

        % Value changed function: menu_dip_soln
        function menu_dip_solnValueChanged(app, event)
            
            %% store current model
            app.model.solns(app.temp.soln_num).dips = app.dips;
            app.model.solns(app.temp.soln_num).fit_int = app.model.fit_int;
            app.model.solns(app.temp.soln_num).fit_samps = app.model.fit_samps;
            app.model.solns(app.temp.soln_num).resid_var = app.model.resid_var;

            %% Change to selected model
            app.temp.soln_num = app.menu_dip_soln.ValueIndex;
            app.dips = app.model.solns(app.temp.soln_num).dips; 
            app.model.fit_int = app.model.solns(app.temp.soln_num).fit_int; 
            plot_time_int(app); app.temp.dip_idx = 1; update_dip_info_panel(app); plot_mri(app);
            if app.check_autoUpdate.Value
                calc_swf(app);
            end
            
            %% clear undo && spinner
            app.temp.undo = [];
            app.temp.undo_counter = 1; 
            app.temp.undo(app.temp.undo_counter).dips = copy_dips(app);
            app.spinner_undo.Value = app.temp.undo_counter; 

        end

        % Button pushed function: btn_delete_dip_soln
        function btn_delete_dip_solnButtonPushed(app, event)
            if ~isempty(app.model.solns)
                cidx = setxor(1:size(app.model.solns,2), app.menu_dip_soln.ValueIndex);
                app.model.solns = app.model.solns(cidx); app.temp.soln_num = 1;
                app.menu_dip_soln.Items = {app.model.solns.Name};
                if ~isempty(app.menu_dip_soln.Items)
                    app.menu_dip_soln.ValueIndex = length(app.menu_dip_soln.Items); %app.temp.soln_num;
                    %% Change to selected model
                    app.temp.soln_num = app.menu_dip_soln.ValueIndex;
                    app.dips = app.model.solns(app.temp.soln_num).dips;
                    app.model.fit_int = app.model.solns(app.temp.soln_num).fit_int;
                    plot_time_int(app); app.temp.dip_idx = 1; update_dip_info_panel(app); plot_mri(app);
                    if app.check_autoUpdate.Value
                        calc_swf(app);
                    end
                else
                    app.temp.soln_num = [];
                end
            end
        end

        % Callback function: btn_plot_swf, check_plot_rms
        function btn_plot_swfButtonPushed(app, event)
            plot_swf(app);
        end

        % Value changed function: slider_trial
        function slider_trialValueChanged(app, event)
            app.edit_trial.Value = app.slider_trial.Value(1);
            app.axes_erp_waves.clo; app.axes_trial_waves.clo;
            app.h_plots.h_overlay_data = [];
            plot_erp(app);
        end

        % Value changed function: edit_trial
        function edit_trialValueChanged(app, event)
            value = app.edit_trial.Value;
            if value>app.slider_trial.Value(2)
                app.slider_trial.Value = [value value];
            else
                app.slider_trial.Value(1) = value;
            end
            slider_trialValueChanged(app,[]);
        end

        % Value changed function: LeadFieldGainEditField
        function LeadFieldGainEditFieldValueChanged(app, event)
            value = app.LeadFieldGainEditField.Value;
            app.temp.convert_factor = 1/value; 
        end

        % Value changed function: spinner_undo
        function spinner_undoValueChanged(app, event)
            value = app.spinner_undo.Value;
            if app.spinner_undo.Value<1
                app.spinner_undo.Value = size(app.temp.undo,2);
            elseif app.spinner_undo.Value>size(app.temp.undo,2)
                app.spinner_undo.Value = 1; %size(app.temp.undo,2);
            end
            drawnow;
            if ~isempty(app.temp.undo(1).dips)
                app.dips = app.temp.undo(app.spinner_undo.Value).dips;
                app.temp.dip_idx = 1; 
                update_dip_info_panel(app);
                update_model(app);
                plot_mri(app);
            end
            app.temp.dip_idx = app.listbox_dips.ValueIndex;
            listbox_dipsDoubleClicked(app, []);
            update_dip_info_panel(app);
            update_mri_pos(app);
        end

        % Value changed function: menu_search_grid
        function menu_search_gridValueChanged(app, event)
            if app.edit_max_iterations.Value<=5000; app.edit_max_iterations.Value = 5000; end
            if strcmp(app.menu_search_grid.Value,'Continuous')
                % if ~contains(app.menu_headmodel.Value,'Spheres') % must be Spheres
                %     app.menu_headmodel.Value = "Spheres(Volume)";
                %     SelectHeadModel(app); drawnow;
                % end
                app.menu_search_type.Items = {'Pattern' 'fmincon' 'fminmax' 'ParticleSwarm' 'Pareto' 'fminsearch' 'Genetic' 'GeneticMulti' 'SimulatedAnnealing'};
                if app.edit_max_iterations.Value > 1000; app.edit_max_iterations.Value = 1000; end
                % app.menu_search_type.Value = 'fmincon'; % default
            elseif strcmp(app.menu_search_grid.Value,'LeadField') && contains(app.menu_headmodel.Value,'Spheres')
                app.menu_search_type.Items = {'Pattern' 'ParticleSwarm' 'fminsearch' 'Pareto' 'Genetic' 'GeneticMulti' 'SimulatedAnnealing'};
                % app.menu_search_type.Value = 'fmincon'; % default
            elseif strcmp(app.menu_search_grid.Value,'LeadField') && ~contains(app.menu_headmodel.Value,'Spheres')
                app.menu_search_type.Items = {'Pattern' 'ParticleSwarm' 'fminsearch' 'Pareto' 'Genetic' 'GeneticMulti' 'SimulatedAnnealing'};
                % app.menu_search_type.Value = 'fmincon'; % default
            elseif strcmp(app.menu_search_grid.Value,'Multistage') && ~contains(app.menu_headmodel.Value,'Spheres')
                app.menu_search_type.Items = {'Pattern' 'ParticleSwarm' 'fminsearch' 'Pareto' 'Genetic' 'GeneticMulti' 'SimulatedAnnealing'};
                % app.menu_search_type.Value = 'fmincon'; % default
            end

        end

        % Value changed function: toggle_plot_true_pos
        function toggle_plot_true_posValueChanged(app, event)
            value = app.toggle_plot_true_pos.Value;
            if isfield(app.h_plots,'h_2D_true_pos')
                delete(app.h_plots.h_2D_true_pos);
                if value==1
                    plot_true_pos(app);
                    % set(app.h_plots.h_2D_true_pos,'MarkerEdgeAlpha',1);
                else
                    % set(app.h_plots.h_2D_true_pos,'MarkerEdgeAlpha',0);
                end
            else
                plot_true_pos(app);
            end


        end

        % Button pushed function: btn_export_SimMEEG
        function btn_export_SimMEEGPushed(app, event)
            app.h.current_inv_soln = size(app.h.inv_soln,2)+1;
            app.h.inv_soln(app.h.current_inv_soln).leadfield = app.anatomy.leadfield;
            app.h.inv_soln(app.h.current_inv_soln).headmodel = app.anatomy.headmodel;
            app.h.inv_soln(app.h.current_inv_soln).sens = app.anatomy.sens;
            if contains(app.menu_headmodel.Value, 'Volume'); ht = 'Whole Brain'; else; ht = 'Cortical Surface'; end
            app.h.inv_soln(app.h.current_inv_soln).headmodel_type = ht;
            app.h.inv_soln(app.h.current_inv_soln).headmodel_mesh = app.anatomy.mesh_volumes(1);
            app.h.inv_soln(app.h.current_inv_soln).fit_params = app.fit_params;
            app.h.inv_soln(app.h.current_inv_soln).params.act_samps = app.model.fit_samps; 
            app.h.inv_soln(app.h.current_inv_soln).params.ctrl_samps = []; 
            
            app.h.inv_soln(app.h.current_inv_soln).Type = 'Dipole';
            app.h.inv_soln(app.h.current_inv_soln).ListBox_name = sprintf('Dipole (%.f-%.f ms) %s', app.model.fit_int*1e3, ht);
            app.h.inv_soln(app.h.current_inv_soln).org_img = zeros(size(app.anatomy.leadfield.pos,1),1);
            dip_pos = reshape([app.dips.dip_pos], size(app.dips,2), 3)'; 
            dip_idx = find_nearest_voxel(dip_pos, app.anatomy.leadfield.pos);
            app.h.inv_soln(app.h.current_inv_soln).peak_idx = dip_idx;
            %% soln

            %% Vector conversion to scalar using "Max" orientation
            if any([app.dips.vect_idx]); vect_ori = 'Max'; else; vect_ori = 'Scalar'; end
            app.h.inv_soln(app.h.current_inv_soln).maxvectorori_Type = vect_ori;

            % setting max_ori for all other leadfields to get a scalar-like leadfield - this is a hack right now but could later do ...
            xdata = rms(app.data.avg_data(app.model.fit_samps,:),1); % rms in fit interval
            lf = app.anatomy.leadfield.H; wts = []; for v=1:size(lf,3); wts(:,:,v) = pinv(lf(:,:,v)); end
            wts = permute(wts, [2 1 3]); for v=1:3; swf(:,v) = squeeze(wts(:,v,:))'*xdata'; end
            [~,midx]=max(swf,[],2); for v=1:size(lf,3); wts2(:,v) = wts(:,midx(v),v); end
            % app.h.inv_soln(app.h.current_inv_soln).soln.wts = zeros(size(app.anatomy.leadfield.H,1), size(app.anatomy.leadfield.H,3));
            app.h.inv_soln(app.h.current_inv_soln).soln.wts = wts2;
            app.h.inv_soln(app.h.current_inv_soln).soln.ori = zeros(size(app.anatomy.leadfield.pos,1),3);

            % finding max orientation within active interval
            for v=1:size(app.dips,2)
                if app.dips(v).vect_idx==1
                    ori_length = rms(app.dips(v).swf(:,app.model.fit_samps)*app.temp.convert_factor,2);
                    [img_val(v), max_ori(v)] = max(ori_length);
                    app.h.inv_soln(app.h.current_inv_soln).soln.wts(:,dip_idx(v)) = app.dips(v).wts(:,max_ori(v));
                    app.h.inv_soln(app.h.current_inv_soln).soln.ori(dip_idx(v),max_ori(v)) = 1;
                else
                    ori = deg2rad(app.dips(v).dip_ori); [x,y,z] = sph2cart(ori(:,1), ori(:,2),1); ori = [x y z];
                    app.h.inv_soln(app.h.current_inv_soln).soln.ori(dip_idx(v),:) = ori;
                    app.h.inv_soln(app.h.current_inv_soln).soln.wts(:,dip_idx(v)) = app.dips(v).wts;
                    img_val(v) = rms(app.dips(v).swf(app.model.fit_samps)*app.temp.convert_factor);
                end
            end
            app.h.inv_soln(app.h.current_inv_soln).soln.Hscalar = [];
            app.h.inv_soln(app.h.current_inv_soln).org_img(dip_idx) = img_val; 

            app.h.inv_soln(app.h.current_inv_soln).soln.P.type = 'Dipole';
            app.h.inv_soln(app.h.current_inv_soln).soln.P.img = app.h.inv_soln(app.h.current_inv_soln).org_img;
            app.h.inv_soln(app.h.current_inv_soln).soln.P.img_org = app.h.inv_soln(app.h.current_inv_soln).org_img;
            app.h.inv_soln(app.h.current_inv_soln).soln.P_ctrl.type = 'Dipole'; 
            app.h.inv_soln(app.h.current_inv_soln).soln.P_ctrl.img = [];
            app.h.inv_soln(app.h.current_inv_soln).soln.RNcov = [];
            app.h.inv_soln(app.h.current_inv_soln).soln.null_thresh = 0; 
            app.h.inv_soln(app.h.current_inv_soln).soln.compute_time = []; 
            app.h.inv_soln(app.h.current_inv_soln).soln.plot_min_max = [0 1];
            app.h.inv_soln(app.h.current_inv_soln).soln.plot_thresh = 0; 

            xnames = {app.h.inv_soln.ListBox_name};
            app.h.listbox_inv_solns.String = xnames; 
            app.h.listbox_inv_solns.Value = app.h.current_inv_soln;
            assignin('base','h',app.h);
            listbox_inv_solns_Callback('dipole'); drawnow;
            sm_calc_post_source_modeling();

        end

        % Selection changed function: btngrp_topo
        function btngrp_topoSelectionChanged(app, event)
            plot_topo(app);
        end

        % Value changed function: edit_topo_scale_YLim1, 
        % ...and 2 other components
        function slider_topo_scaleValueChanged(app, event)
            
            if contains(event.Source.Tag,'slider')
                % value = app.slider_topo_scale.Value;
            else
                value = [app.edit_topo_scale_YLim1.Value app.edit_topo_scale_YLim2.Value];
                app.slider_topo_scale.Limits = value;
                app.slider_topo_scale.MajorTicks = value(1):range(value)/5:value(2);
                app.slider_topo_scale.MinorTicks = value(1):range(value)/10:value(2);
            end
            if diff(app.slider_topo_scale.Value)~=0
                if isfield(app.temp,'colorbar_pos')
                    app.Axes_3D.CLim = app.slider_topo_scale.Value;
                    % set(app.h_plots.topo_colorbar,'Position',app.temp.colorbar_pos);
                    cpos = app.temp.colorbar_pos;
                    spos = app.slider_topo_scale.Position;

                    cpos(1) = spos(1) + (spos(3)*( app.slider_topo_scale.Value(1)-app.slider_topo_scale.Limits(1) ) /range(app.slider_topo_scale.Limits) );
                    cpos(3) = spos(3) * (range(app.slider_topo_scale.Value)./range(app.slider_topo_scale.Limits)); % length of colorbar

                    set(app.h_plots.topo_colorbar,'Position',cpos);
                end
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SourceModeling.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {831, 831};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SourceModeling = uifigure('Visible', 'off');
            app.SourceModeling.Name = 'BRANE Lab Dipole Fitting';
            app.SourceModeling.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SourceModeling.Tag = 'MainProg';
            app.FileMenu = uimenu(app.SourceModeling);
            app.FileMenu.Text = 'File';
            app.LoadAnatomyMenu = uimenu(app.FileMenu);
            app.LoadAnatomyMenu.MenuSelectedFcn = createCallbackFcn(app, @btn_load_anatomyPushed, true);
            app.LoadAnatomyMenu.Text = 'Load Anatomy';
            app.LoadERPMenu = uimenu(app.FileMenu);
            app.LoadERPMenu.MenuSelectedFcn = createCallbackFcn(app, @btn_load_erpPushed, true);
            app.LoadERPMenu.Text = 'Load ERP';
            app.LoadSolutionMenu = uimenu(app.FileMenu);
            app.LoadSolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @btn_load_dip_solnButtonPushed, true);
            app.LoadSolutionMenu.Text = 'Load Solution';
            app.SaveSolutionMenu = uimenu(app.FileMenu);
            app.SaveSolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @btn_save_dip_solnButtonPushed, true);
            app.SaveSolutionMenu.Text = 'Save Solution';
            app.AboutMenu = uimenu(app.SourceModeling);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.SourceModeling);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.panel_main_menu = uipanel(app.RightPanel);
            app.panel_main_menu.Title = 'MainMenu';
            app.btn_load_anatomy = uibutton(app.panel_main_menu, 'push');
            app.btn_load_anatomy.ButtonPushedFcn = createCallbackFcn(app, @btn_load_anatomyPushed, true);
            app.btn_load_anatomy.Tooltip = {'Anatomy *.mat file needs to be in SimMEEG format with variables: '; 'sens_eeg, sens_meg, headmodel_eeg_cortex, headmodel_eeg_volume,  headmodel_meg_cortex, '; 'headmodel_meg_colume, ...'; ' '};
            app.btn_load_anatomy.Text = 'Load Anatomy';
            app.SensorTypeDropDownLabel = uilabel(app.panel_main_menu);
            app.SensorTypeDropDownLabel.Text = 'Sensor Type';
            app.menu_sensor_type = uidropdown(app.panel_main_menu);
            app.menu_sensor_type.Items = {};
            app.menu_sensor_type.ValueChangedFcn = createCallbackFcn(app, @menu_sensor_typeValueChanged, true);
            app.menu_sensor_type.Value = {};
            app.HeadModelDropDownLabel = uilabel(app.panel_main_menu);
            app.HeadModelDropDownLabel.Text = 'Head Model';
            app.menu_headmodel = uidropdown(app.panel_main_menu);
            app.menu_headmodel.Items = {'Volume', 'Cortex', 'Spheres(Volume)', 'Spheres(Cortex)'};
            app.menu_headmodel.ValueChangedFcn = createCallbackFcn(app, @menu_headmodelValueChanged, true);
            app.menu_headmodel.Value = 'Volume';
            app.btn_load_erp = uibutton(app.panel_main_menu, 'push');
            app.btn_load_erp.ButtonPushedFcn = createCallbackFcn(app, @btn_load_erpPushed, true);
            app.btn_load_erp.Tooltip = {'variables in .mat must be:'; ''; 'data = [samples x sensors x trials]'; 'lat = latencies of samples'; 'srate = sample rate'; 'sens_label = sensor labels e.g., {"FPz" "AFz" ...}'};
            app.btn_load_erp.Text = 'Load ERP/ERF';
            app.NumberSensorsDropDownLabel = uilabel(app.panel_main_menu);
            app.NumberSensorsDropDownLabel.Text = 'Number Sensors';
            app.menu_num_sensors = uidropdown(app.panel_main_menu);
            app.menu_num_sensors.Items = {};
            app.menu_num_sensors.ValueChangedFcn = createCallbackFcn(app, @menu_num_sensorsValueChanged, true);
            app.menu_num_sensors.Value = {};
            app.RunningLabel = uilabel(app.panel_main_menu);
            app.RunningLabel.Text = 'Running';
            app.lamp_run = uilamp(app.panel_main_menu);
            app.text_RunTime = uilabel(app.panel_main_menu);
            app.text_RunTime.Text = 'Run Time = ';
            app.LeadFieldGainEditFieldLabel = uilabel(app.panel_main_menu);
            app.LeadFieldGainEditFieldLabel.Text = 'Lead Field Gain';
            app.LeadFieldGainEditField = uieditfield(app.panel_main_menu, 'numeric');
            app.LeadFieldGainEditField.ValueChangedFcn = createCallbackFcn(app, @LeadFieldGainEditFieldValueChanged, true);
            app.LeadFieldGainEditField.Value = 1;
            app.tab_modeling_grp = uitabgroup(app.RightPanel);
            app.tab_dipfit = uitab(app.tab_modeling_grp);
            app.tab_dipfit.Title = 'Dipole';
            app.panel_dipfit_results = uipanel(app.tab_dipfit);
            app.panel_dipfit_results.Title = 'Fit Results';
            app.axes_dip_resid_var = uiaxes(app.panel_dipfit_results);
            title(app.axes_dip_resid_var, 'Residual Variance')
            ylabel(app.axes_dip_resid_var, 'RV (%)')
            zlabel(app.axes_dip_resid_var, 'Z')
            colormap(app.axes_dip_resid_var, 'parula')
            app.axes_dip_waves = uiaxes(app.panel_dipfit_results);
            title(app.axes_dip_waves, 'Source Waveforms')
            xlabel(app.axes_dip_waves, 'Time (sec)')
            ylabel(app.axes_dip_waves, 'Source Strength (nA)')
            zlabel(app.axes_dip_waves, 'Z')
            app.axes_dip_waves.ButtonDownFcn = createCallbackFcn(app, @axes_dip_wavesButtonDown, true);
            colormap(app.axes_dip_waves, 'parula')
            app.edit_axes_swf_YLim2 = uieditfield(app.panel_dipfit_results, 'numeric');
            app.edit_axes_swf_YLim2.ValueChangedFcn = createCallbackFcn(app, @edit_axes_swf_YLim, true);
            app.edit_axes_swf_YLim2.Value = 100;
            app.edit_axes_swf_YLim1 = uieditfield(app.panel_dipfit_results, 'numeric');
            app.edit_axes_swf_YLim1.ValueChangedFcn = createCallbackFcn(app, @edit_axes_swf_YLim, true);
            app.slider_dip_waves_scale = uislider(app.panel_dipfit_results, 'range');
            app.slider_dip_waves_scale.Limits = [-100 100];
            app.slider_dip_waves_scale.ValueChangedFcn = createCallbackFcn(app, @slider_dip_waves_scaleValueChanging, true);
            app.slider_dip_waves_scale.Value = [-100 100];
            app.OffsetEditFieldLabel = uilabel(app.panel_dipfit_results);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.edit_yoffset_swf = uieditfield(app.panel_dipfit_results, 'numeric');
            app.edit_yoffset_swf.ValueChangedFcn = createCallbackFcn(app, @edit_yoffset_swfValueChanged, true);
            app.edit_yoffset_swf.Value = 100;
            app.check_plot_rms = uicheckbox(app.panel_dipfit_results);
            app.check_plot_rms.ValueChangedFcn = createCallbackFcn(app, @btn_plot_swfButtonPushed, true);
            app.check_plot_rms.Text = 'RMS';
            app.btn_plot_swf = uibutton(app.panel_dipfit_results, 'push');
            app.btn_plot_swf.ButtonPushedFcn = createCallbackFcn(app, @btn_plot_swfButtonPushed, true);
            app.btn_plot_swf.Text = 'plot';
            app.panel_dip_info = uipanel(app.tab_dipfit);
            app.panel_dip_info.Title = 'Dipole Info';
            app.FitOrderEditFieldLabel = uilabel(app.panel_dip_info);
            app.FitOrderEditFieldLabel.Text = 'Fit Order';
            app.edit_dip_order = uieditfield(app.panel_dip_info, 'numeric');
            app.edit_dip_order.Limits = [0 Inf];
            app.edit_dip_order.ValueDisplayFormat = '%.0f';
            app.edit_dip_order.ValueChangedFcn = createCallbackFcn(app, @update_dipsCallback, true);
            app.edit_dip_order.Enable = 'off';
            app.edit_dip_order.Value = 1;
            app.check_dip_vect = uicheckbox(app.panel_dip_info);
            app.check_dip_vect.ValueChangedFcn = createCallbackFcn(app, @check_dip_vectValueChanged, true);
            app.check_dip_vect.Text = 'Vector';
            app.check_dip_vect.Value = true;
            app.OrientationazelLabel = uilabel(app.panel_dip_info);
            app.OrientationazelLabel.Text = 'Orientation (az, el)';
            app.OriLabel = uilabel(app.panel_dip_info);
            app.OriLabel.Text = 'Ori:';
            app.menu_dip_ori_fit = uidropdown(app.panel_dip_info);
            app.menu_dip_ori_fit.Items = {'free', 'fixed'};
            app.menu_dip_ori_fit.ValueChangedFcn = createCallbackFcn(app, @menu_dip_ori_fitValueChanged, true);
            app.menu_dip_ori_fit.Enable = 'off';
            app.menu_dip_ori_fit.Value = 'fixed';
            app.edit_dip_search_radius = uieditfield(app.panel_dip_info, 'numeric');
            app.edit_dip_search_radius.Limits = [-10000 10000];
            app.edit_dip_search_radius.ValueChangedFcn = createCallbackFcn(app, @update_dipsCallback, true);
            app.edit_dip_search_radius.Enable = 'off';
            app.edit_dip_search_radius.Tooltip = {'Search Radius around original dipole position to search for a solution.  Set to "-1" for no search.'};
            app.edit_dip_search_radius.Value = -1;
            app.check_dip_search_radius = uicheckbox(app.panel_dip_info);
            app.check_dip_search_radius.ValueChangedFcn = createCallbackFcn(app, @check_dip_search_radiusValueChanged, true);
            app.check_dip_search_radius.Tooltip = {'Confines search to be within X mm from oringal source locations'};
            app.check_dip_search_radius.Text = 'Search Radius (mm)';
            app.menu_dip_symmetrical = uidropdown(app.panel_dip_info);
            app.menu_dip_symmetrical.Items = {};
            app.menu_dip_symmetrical.ValueChangedFcn = createCallbackFcn(app, @update_dipsCallback, true);
            app.menu_dip_symmetrical.Enable = 'off';
            app.menu_dip_symmetrical.Value = {};
            app.LocLabel = uilabel(app.panel_dip_info);
            app.LocLabel.Text = 'Loc:';
            app.menu_dip_loc_fit = uidropdown(app.panel_dip_info);
            app.menu_dip_loc_fit.Items = {'free', 'fixed', 'symmetric', 'bound'};
            app.menu_dip_loc_fit.ValueChangedFcn = createCallbackFcn(app, @menu_dip_loc_fitValueChanged, true);
            app.menu_dip_loc_fit.Value = 'free';
            app.LocationLabel = uilabel(app.panel_dip_info);
            app.LocationLabel.Text = 'Location (mm)';
            app.DipoleListBoxLabel = uilabel(app.panel_dip_info);
            app.DipoleListBoxLabel.Text = 'Dipole';
            app.listbox_dips = uilistbox(app.panel_dip_info);
            app.listbox_dips.Items = {};
            app.listbox_dips.ValueChangedFcn = createCallbackFcn(app, @listbox_dipsClicked, true);
            app.listbox_dips.ClickedFcn = createCallbackFcn(app, @listbox_dipsClicked, true);
            app.listbox_dips.DoubleClickedFcn = createCallbackFcn(app, @listbox_dipsDoubleClicked, true);
            app.listbox_dips.Value = {};
            app.switch_dip_fit = uiswitch(app.panel_dip_info, 'slider');
            app.switch_dip_fit.Items = {'No Fit', 'Fit'};
            app.switch_dip_fit.ValueChangedFcn = createCallbackFcn(app, @switch_dip_fitValueChanged, true);
            app.switch_dip_fit.Enable = 'off';
            app.switch_dip_fit.Value = 'No Fit';
            app.switch_dip_on = uiswitch(app.panel_dip_info, 'slider');
            app.switch_dip_on.ValueChangedFcn = createCallbackFcn(app, @switch_dip_onValueChanged, true);
            app.edit_dip_ori = uieditfield(app.panel_dip_info, 'text');
            app.edit_dip_ori.ValueChangedFcn = createCallbackFcn(app, @update_dipsCallback, true);
            app.edit_dip_ori.Visible = 'off';
            app.edit_dip_ori.Value = '0 90';
            app.edit_dip_pos = uieditfield(app.panel_dip_info, 'text');
            app.edit_dip_pos.ValueChangedFcn = createCallbackFcn(app, @update_dipsCallback, true);
            app.edit_dip_pos.Value = '-10.2 35.4 58.2';
            app.btn_dip_delete_all = uibutton(app.panel_dip_info, 'push');
            app.btn_dip_delete_all.ButtonPushedFcn = createCallbackFcn(app, @btn_dip_deletePushed, true);
            app.btn_dip_delete_all.Text = 'Delete All';
            app.btn_dip_delete = uibutton(app.panel_dip_info, 'push');
            app.btn_dip_delete.ButtonPushedFcn = createCallbackFcn(app, @btn_dip_deletePushed, true);
            app.btn_dip_delete.Text = 'Delete';
            app.btn_dip_add = uibutton(app.panel_dip_info, 'push');
            app.btn_dip_add.ButtonPushedFcn = createCallbackFcn(app, @btn_dip_addButtonPushed, true);
            app.btn_dip_add.Text = 'Add';
            app.color_dip_clr = uicolorpicker(app.panel_dip_info);
            app.color_dip_clr.Value = [0 0.6 0];
            app.color_dip_clr.ValueChangedFcn = createCallbackFcn(app, @update_dipsCallback, true);
            app.edit_dip_sym_dist = uieditfield(app.panel_dip_info, 'numeric');
            app.edit_dip_sym_dist.Limits = [0 10000000000];
            app.edit_dip_sym_dist.ValueChangedFcn = createCallbackFcn(app, @update_dipsCallback, true);
            app.edit_dip_sym_dist.Enable = 'off';
            app.edit_dip_sym_dist.Tooltip = {'Search Radius around original dipole position to search for a solution.  Set to "-1" for no search.'};
            app.edit_dip_sym_dist.Value = 20;
            app.check_dip_sym_dist = uicheckbox(app.panel_dip_info);
            app.check_dip_sym_dist.ValueChangedFcn = createCallbackFcn(app, @update_dipsCallback, true);
            app.check_dip_sym_dist.Enable = 'off';
            app.check_dip_sym_dist.Tooltip = {'Confines search to be within X mm from oringal source locations'};
            app.check_dip_sym_dist.Text = 'Min Distance';
            app.btn_invert_dip = uibutton(app.panel_dip_info, 'push');
            app.btn_invert_dip.ButtonPushedFcn = createCallbackFcn(app, @btn_invert_dipButtonPushed, true);
            app.btn_invert_dip.Text = 'Invert';
            app.panel_dipfit_params = uipanel(app.tab_dipfit);
            app.panel_dipfit_params.Title = 'Fit Parameters';
            app.btngrp_fit_type = uibuttongroup(app.panel_dipfit_params);
            app.btngrp_fit_type.Visible = 'off';
            app.btn_fit_moving = uitogglebutton(app.btngrp_fit_type);
            app.btn_fit_moving.Text = 'Moving';
            app.btn_fit_spatiotemp = uitogglebutton(app.btngrp_fit_type);
            app.btn_fit_spatiotemp.Text = 'SpatioTemp';
            app.btn_fit_spatiotemp.Value = true;
            app.SearchTypeLabel = uilabel(app.panel_dipfit_params);
            app.SearchTypeLabel.Text = 'Search Type';
            app.menu_search_type = uidropdown(app.panel_dipfit_params);
            app.menu_search_type.Items = {};
            app.menu_search_type.Value = {};
            app.MaxIterLabel = uilabel(app.panel_dipfit_params);
            app.MaxIterLabel.Text = 'Max Iter';
            app.edit_max_iterations = uieditfield(app.panel_dipfit_params, 'numeric');
            app.edit_max_iterations.Tooltip = {'Max iterations to perform search.'};
            app.edit_max_iterations.Value = 5000;
            app.check_verbose = uicheckbox(app.panel_dipfit_params);
            app.check_verbose.Tooltip = {'Print out iteration results in matlab command window'};
            app.check_verbose.Text = 'Verbose';
            app.check_iterations = uicheckbox(app.panel_dipfit_params);
            app.check_iterations.Tooltip = {'Plot iterations on MRI. Warning this might take time for some "Search Types"'};
            app.check_iterations.Text = 'Plot Iter';
            app.btn_start_fit = uibutton(app.panel_dipfit_params, 'push');
            app.btn_start_fit.ButtonPushedFcn = createCallbackFcn(app, @btn_start_fitPushed, true);
            app.btn_start_fit.Text = 'Start Fit';
            app.check_autoUpdate = uicheckbox(app.panel_dipfit_params);
            app.check_autoUpdate.Tooltip = {'Auto update after moving dipoles:  dipole fits, residual variances, source waveforms.'};
            app.check_autoUpdate.Text = 'Update';
            app.btn_load_dip_soln = uibutton(app.panel_dipfit_params, 'push');
            app.btn_load_dip_soln.ButtonPushedFcn = createCallbackFcn(app, @btn_load_dip_solnButtonPushed, true);
            app.btn_load_dip_soln.Text = 'Load Soln';
            app.btn_save_dip_soln = uibutton(app.panel_dipfit_params, 'push');
            app.btn_save_dip_soln.ButtonPushedFcn = createCallbackFcn(app, @btn_save_dip_solnButtonPushed, true);
            app.btn_save_dip_soln.Text = 'Save Soln';
            app.check_parallel_compute = uicheckbox(app.panel_dipfit_params);
            app.check_parallel_compute.Tooltip = {'Print out iteration results in matlab command window'};
            app.check_parallel_compute.Text = 'Parallel';
            app.check_parallel_compute.Value = true;
            app.MaxIterLabel_2 = uilabel(app.panel_dipfit_params);
            app.MaxIterLabel_2.Text = 'MaxTime';
            app.edit_max_time = uieditfield(app.panel_dipfit_params, 'numeric');
            app.edit_max_time.Tooltip = {'Max iterations to perform search.'};
            app.edit_max_time.Value = 15;
            app.btn_new_dip_soln = uibutton(app.panel_dipfit_params, 'push');
            app.btn_new_dip_soln.ButtonPushedFcn = createCallbackFcn(app, @btn_new_dip_solnButtonPushed, true);
            app.btn_new_dip_soln.Text = 'New Soln';
            app.menu_dip_soln = uidropdown(app.panel_dipfit_params);
            app.menu_dip_soln.Items = {'Soln 1'};
            app.menu_dip_soln.ValueChangedFcn = createCallbackFcn(app, @menu_dip_solnValueChanged, true);
            app.menu_dip_soln.Value = 'Soln 1';
            app.btn_copy_dip_soln = uibutton(app.panel_dipfit_params, 'push');
            app.btn_copy_dip_soln.Enable = 'off';
            app.btn_copy_dip_soln.Text = 'Copy Soln';
            app.btn_delete_dip_soln = uibutton(app.panel_dipfit_params, 'push');
            app.btn_delete_dip_soln.ButtonPushedFcn = createCallbackFcn(app, @btn_delete_dip_solnButtonPushed, true);
            app.btn_delete_dip_soln.Text = 'Del Soln';
            app.btn_save_all_dip_soln = uibutton(app.panel_dipfit_params, 'push');
            app.btn_save_all_dip_soln.ButtonPushedFcn = createCallbackFcn(app, @btn_save_all_dip_solnButtonPushed, true);
            app.btn_save_all_dip_soln.Text = 'Save All';
            app.undoSpinnerLabel = uilabel(app.panel_dipfit_params);
            app.undoSpinnerLabel.Text = 'undo';
            app.spinner_undo = uispinner(app.panel_dipfit_params);
            app.spinner_undo.ValueChangedFcn = createCallbackFcn(app, @spinner_undoValueChanged, true);
            app.GridDropDownLabel = uilabel(app.panel_dipfit_params);
            app.GridDropDownLabel.Text = 'Grid';
            app.menu_search_grid = uidropdown(app.panel_dipfit_params);
            app.menu_search_grid.Items = {'LeadField', 'Continuous'};
            app.menu_search_grid.ValueChangedFcn = createCallbackFcn(app, @menu_search_gridValueChanged, true);
            app.menu_search_grid.Value = 'LeadField';
            app.MaxIterLabel_3 = uilabel(app.panel_dipfit_params);
            app.MaxIterLabel_3.Text = 'Tolerance';
            app.edit_search_tolerance = uieditfield(app.panel_dipfit_params, 'numeric');
            app.edit_search_tolerance.ValueDisplayFormat = '%11.2g';
            app.edit_search_tolerance.Tooltip = {'Function and Step Tolerance of search optimizer, when applicable'};
            app.edit_search_tolerance.Value = 0.05;
            app.panel_ERP = uipanel(app.RightPanel);
            app.panel_ERP.Title = 'ERP/ERF';
            app.axes_erp_waves = uiaxes(app.panel_ERP);
            title(app.axes_erp_waves, 'ERP/ERF')
            xlabel(app.axes_erp_waves, 'Time (sec)')
            ylabel(app.axes_erp_waves, 'Amplitude')
            zlabel(app.axes_erp_waves, 'Z')
            app.axes_erp_waves.ButtonDownFcn = createCallbackFcn(app, @axes_erp_wavesButtonDown, true);
            colormap(app.axes_erp_waves, 'parula')
            app.axes_trial_waves = uiaxes(app.panel_ERP);
            title(app.axes_trial_waves, 'Trial')
            xlabel(app.axes_trial_waves, 'Time (sec)')
            ylabel(app.axes_trial_waves, 'Amplitude')
            zlabel(app.axes_trial_waves, 'Z')
            colormap(app.axes_trial_waves, 'parula')
            app.SensorListBoxLabel = uilabel(app.panel_ERP);
            app.SensorListBoxLabel.Text = 'Sensor';
            app.listbox_sensors = uilistbox(app.panel_ERP);
            app.listbox_sensors.Multiselect = 'on';
            app.listbox_sensors.ClickedFcn = createCallbackFcn(app, @listbox_sensorsClicked, true);
            app.listbox_sensors.Value = {'Item 1'};
            app.btn_plot_erp = uibutton(app.panel_ERP, 'push');
            app.btn_plot_erp.ButtonPushedFcn = createCallbackFcn(app, @btn_plot_erpButtonPushed, true);
            app.btn_plot_erp.Text = 'Plot';
            app.TrialsSliderLabel = uilabel(app.panel_ERP);
            app.TrialsSliderLabel.Text = 'Trials';
            app.slider_trial = uislider(app.panel_ERP, 'range');
            app.slider_trial.Limits = [0 1];
            app.slider_trial.ValueChangedFcn = createCallbackFcn(app, @slider_trialValueChanged, true);
            app.slider_trial.Step = 1;
            app.slider_trial.Value = [0 1];
            app.FitIntervalEditFieldLabel = uilabel(app.panel_ERP);
            app.FitIntervalEditFieldLabel.Text = 'Fit Interval';
            app.edit_fit_interval = uieditfield(app.panel_ERP, 'text');
            app.edit_fit_interval.ValueChangedFcn = createCallbackFcn(app, @edit_fit_intervalValueChanged, true);
            app.edit_fit_interval.Value = '0.100  0.250';
            app.TrialSpinnerLabel = uilabel(app.panel_ERP);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.edit_trial = uispinner(app.panel_ERP);
            app.edit_trial.Limits = [1 Inf];
            app.edit_trial.RoundFractionalValues = 'on';
            app.edit_trial.ValueChangedFcn = createCallbackFcn(app, @edit_trialValueChanged, true);
            app.edit_trial.Value = 1;
            app.btn_reref = uibutton(app.panel_ERP, 'state');
            app.btn_reref.ValueChangedFcn = createCallbackFcn(app, @btn_rerefValueChanged, true);
            app.btn_reref.Text = 'Re-ref';
            app.toggle_view_data = uibutton(app.panel_ERP, 'state');
            app.toggle_view_data.ValueChangedFcn = createCallbackFcn(app, @toggle_view_ValueChanged, true);
            app.toggle_view_data.Text = 'Data';
            app.toggle_view_data.Value = true;
            app.toggle_view_residual = uibutton(app.panel_ERP, 'state');
            app.toggle_view_residual.ValueChangedFcn = createCallbackFcn(app, @toggle_view_ValueChanged, true);
            app.toggle_view_residual.Text = 'Residual';
            app.toggle_view_model = uibutton(app.panel_ERP, 'state');
            app.toggle_view_model.ValueChangedFcn = createCallbackFcn(app, @toggle_view_ValueChanged, true);
            app.toggle_view_model.Text = 'Model';
            app.text_erp_file = uilabel(app.panel_ERP);
            app.text_erp_file.Text = 'File:';
            app.btn_clear_erp_plot = uibutton(app.panel_ERP, 'push');
            app.btn_clear_erp_plot.ButtonPushedFcn = createCallbackFcn(app, @btn_clear_erp_plotPushed, true);
            app.btn_clear_erp_plot.Text = 'Clear';
            app.panel_MRIViewer = uipanel(app.RightPanel);
            app.panel_MRIViewer.Title = 'MRIViewer';
            app.Axes_Sagital = uiaxes(app.panel_MRIViewer);
            title(app.Axes_Sagital, 'Sagital')
            xlabel(app.Axes_Sagital, 'X')
            ylabel(app.Axes_Sagital, 'Y')
            zlabel(app.Axes_Sagital, 'Z')
            colormap(app.Axes_Sagital, 'parula')
            app.Axes_Axial = uiaxes(app.panel_MRIViewer);
            title(app.Axes_Axial, 'Axial')
            xlabel(app.Axes_Axial, 'X')
            ylabel(app.Axes_Axial, 'Y')
            zlabel(app.Axes_Axial, 'Z')
            colormap(app.Axes_Axial, 'parula')
            app.Axes_Coronal = uiaxes(app.panel_MRIViewer);
            title(app.Axes_Coronal, 'Coronal')
            xlabel(app.Axes_Coronal, 'X')
            ylabel(app.Axes_Coronal, 'Y')
            zlabel(app.Axes_Coronal, 'Z')
            colormap(app.Axes_Coronal, 'parula')
            app.Axes_3D = uiaxes(app.panel_MRIViewer);
            title(app.Axes_3D, '3D')
            xlabel(app.Axes_3D, 'X')
            ylabel(app.Axes_3D, 'Y')
            zlabel(app.Axes_3D, 'Z')
            colormap(app.Axes_3D, 'parula')
            app.btn_plot_mri = uibutton(app.panel_MRIViewer, 'push');
            app.btn_plot_mri.ButtonPushedFcn = createCallbackFcn(app, @btn_plot_mriPushed, true);
            app.btn_plot_mri.Text = 'Plot MRI';
            app.DTransparencyLabel = uilabel(app.panel_MRIViewer);
            app.DTransparencyLabel.Text = 'Scalp';
            app.check_radiological_view = uicheckbox(app.panel_MRIViewer);
            app.check_radiological_view.ValueChangedFcn = createCallbackFcn(app, @check_radiological_viewValueChanged, true);
            app.check_radiological_view.Tooltip = {'Plot MRI in radiological view'};
            app.check_radiological_view.Text = 'Radiological';
            app.slider_brain_transparency = uislider(app.panel_MRIViewer);
            app.slider_brain_transparency.MajorTicks = [];
            app.slider_brain_transparency.ValueChangedFcn = createCallbackFcn(app, @slider_transparencyValueChanged, true);
            app.slider_brain_transparency.Value = 25;
            app.slider_scalp_transparency = uislider(app.panel_MRIViewer);
            app.slider_scalp_transparency.MajorTicks = [];
            app.slider_scalp_transparency.ValueChangedFcn = createCallbackFcn(app, @slider_transparencyValueChanged, true);
            app.slider_scalp_transparency.Value = 20;
            app.slider_topo_transparency = uislider(app.panel_MRIViewer);
            app.slider_topo_transparency.MajorTicks = [];
            app.slider_topo_transparency.ValueChangedFcn = createCallbackFcn(app, @slider_transparencyValueChanged, true);
            app.slider_topo_transparency.Value = 50;
            app.MRITransparencyLabel = uilabel(app.panel_MRIViewer);
            app.MRITransparencyLabel.Text = 'Transparencies: MRI';
            app.slider_mri_transparency = uislider(app.panel_MRIViewer);
            app.slider_mri_transparency.MajorTicks = [];
            app.slider_mri_transparency.ValueChangedFcn = createCallbackFcn(app, @slider_transparencyValueChanged, true);
            app.slider_mri_transparency.Value = 50;
            app.DTransparencyLabel_2 = uilabel(app.panel_MRIViewer);
            app.DTransparencyLabel_2.Text = 'Brain';
            app.DTransparencyLabel_3 = uilabel(app.panel_MRIViewer);
            app.DTransparencyLabel_3.Text = 'Topo';
            app.btn_plot_headmodel = uibutton(app.panel_MRIViewer, 'state');
            app.btn_plot_headmodel.ValueChangedFcn = createCallbackFcn(app, @btn_plot_headmodelValueChanged, true);
            app.btn_plot_headmodel.Text = 'Hdm';
            app.toggle_plot_true_pos = uibutton(app.panel_MRIViewer, 'state');
            app.toggle_plot_true_pos.ValueChangedFcn = createCallbackFcn(app, @toggle_plot_true_posValueChanged, true);
            app.toggle_plot_true_pos.Text = 'True';
            app.toggle_plot_true_pos.Value = true;
            app.btngrp_topo = uibuttongroup(app.panel_MRIViewer);
            app.btngrp_topo.SelectionChangedFcn = createCallbackFcn(app, @btngrp_topoSelectionChanged, true);
            app.btn_topo_data = uitogglebutton(app.btngrp_topo);
            app.btn_topo_data.Text = 'Data';
            app.btn_topo_data.Value = true;
            app.btn_topo_model = uitogglebutton(app.btngrp_topo);
            app.btn_topo_model.Text = 'Model';
            app.btn_topo_residual = uitogglebutton(app.btngrp_topo);
            app.btn_topo_residual.Text = 'Residual';
            app.edit_topo_scale_YLim1 = uieditfield(app.panel_MRIViewer, 'numeric');
            app.edit_topo_scale_YLim1.ValueChangedFcn = createCallbackFcn(app, @slider_topo_scaleValueChanged, true);
            app.edit_topo_scale_YLim1.Tag = 'edit_topo_scale_YLim1';
            app.edit_topo_scale_YLim1.Value = -20;
            app.edit_topo_scale_YLim2 = uieditfield(app.panel_MRIViewer, 'numeric');
            app.edit_topo_scale_YLim2.ValueChangedFcn = createCallbackFcn(app, @slider_topo_scaleValueChanged, true);
            app.edit_topo_scale_YLim2.Tag = 'edit_topo_scale_YLim2';
            app.edit_topo_scale_YLim2.Value = 20;
            app.TopographyScaleLabel = uilabel(app.panel_MRIViewer);
            app.TopographyScaleLabel.Text = 'Topography Scale';
            app.slider_topo_scale = uislider(app.panel_MRIViewer, 'range');
            app.slider_topo_scale.Limits = [-20 20];
            app.slider_topo_scale.MajorTicks = [-20 -10 0 10 20];
            app.slider_topo_scale.ValueChangedFcn = createCallbackFcn(app, @slider_topo_scaleValueChanged, true);
            app.slider_topo_scale.MinorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.slider_topo_scale.Tag = 'slider_topo_scale';
            app.slider_topo_scale.Value = [-20 20];
            app.btn_export_SimMEEG = uibutton(app.RightPanel, 'push');
            app.btn_export_SimMEEG.ButtonPushedFcn = createCallbackFcn(app, @btn_export_SimMEEGPushed, true);
            app.btn_export_SimMEEG.Text = 'Export to SimMEEG';
            % Show the figure after all components are created
            app.SourceModeling.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BL_DipoleModeling_SimMEEG(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SourceModeling)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SourceModeling)
        end
    end
end